classdef PhasorPlot_TCSPCFLIM_20200517_GitHub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ImagesOri                       matlab.ui.container.Panel
        CenterPanel                     matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        IntroductionTab                 matlab.ui.container.Tab
        AGlobalApproachtoTCSPCDataAnalysisLabel  matlab.ui.control.Label
        ThisapplicationallowsforLabel   matlab.ui.control.Label
        PhasoranalysisGenerateslifetimeimagesandtabulatedvaluesLabel  matlab.ui.control.Label
        MultifileprocessingbyphasoranalysisLabel_2  matlab.ui.control.Label
        tocorrespondingphasorsinthephasorplotLabel  matlab.ui.control.Label
        MultifileprocessingbyphasoranalysisLabel_3  matlab.ui.control.Label
        tocorrespondingpixelsinthecellimageLabel  matlab.ui.control.Label
        Pleasereportanyerrorsbugstocwc53camacukLabel  matlab.ui.control.Label
        usingPhasorPlotsLabel           matlab.ui.control.Label
        NotesLabel                      matlab.ui.control.Label
        RequiresallmscriptsincludedinthezippedfolderLabel  matlab.ui.control.Label
        RequirestheComputerVisionToolboxpleaseinstallbeforehandLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MultifileprocessingbyphasoranalysisLabel_4  matlab.ui.control.Label
        OperatesonBHsdtfilessavedinScanSyncInmodeLabel  matlab.ui.control.Label
        SingleimageprocessingonlyLabel  matlab.ui.control.Label
        RequiresMATLAB2019bruntimeLabel  matlab.ui.control.Label
        MultiacquisitionTab             matlab.ui.container.Tab
        TabGroup_2                      matlab.ui.container.TabGroup
        LaserSettingsTab_2              matlab.ui.container.Tab
        MHzLabel_2                      matlab.ui.control.Label
        RepetitionRateEditField_2Label  matlab.ui.control.Label
        RepetitionRateEditField_2       matlab.ui.control.NumericEditField
        SameasSingleImageCheckBox       matlab.ui.control.CheckBox
        CellImageTab_2                  matlab.ui.container.Tab
        nsLabel_5                       matlab.ui.control.Label
        LifetimeRangeEditField_2Label   matlab.ui.control.Label
        LifetimeRangeEditField_2        matlab.ui.control.NumericEditField
        toEditField_2Label_2            matlab.ui.control.Label
        toEditField_3                   matlab.ui.control.NumericEditField
        BrowseButton_7                  matlab.ui.control.Button
        BrowseButton_8                  matlab.ui.control.Button
        CellMasktifEditField_2          matlab.ui.control.EditField
        photonspxLabel_2                matlab.ui.control.Label
        MaskingButtonGroup              matlab.ui.container.ButtonGroup
        MasktifButton_2                 matlab.ui.control.RadioButton
        IntensityButton_2               matlab.ui.control.RadioButton
        OrmaskbyintensityEditField_2    matlab.ui.control.NumericEditField
        CellImageFolderEditFieldLabel   matlab.ui.control.Label
        CellImageFolderEditField        matlab.ui.control.EditField
        ImageNameEditFieldLabel         matlab.ui.control.Label
        ImageNameEditField              matlab.ui.control.EditField
        toEditField_2Label_3            matlab.ui.control.Label
        tointensityEditField_3          matlab.ui.control.NumericEditField
        NoofImagesEditField             matlab.ui.control.NumericEditField
        NoofImagesButton                matlab.ui.control.Button
        SameasSingleImageCheckBox_3     matlab.ui.control.CheckBox
        IRFTab_2                        matlab.ui.container.Tab
        BrowseButton_6                  matlab.ui.control.Button
        nsLabel_4                       matlab.ui.control.Label
        IRFImagesdtEditField_2Label     matlab.ui.control.Label
        IRFImagesdtEditField_2          matlab.ui.control.EditField
        KnownLifetimeEditField_2Label   matlab.ui.control.Label
        KnownLifetimeEditField_2        matlab.ui.control.NumericEditField
        SameasSingleImageCheckBox_2     matlab.ui.control.CheckBox
        StartButton_2                   matlab.ui.control.StateButton
        CommandsTextArea_2Label         matlab.ui.control.Label
        CommandsTextArea_2              matlab.ui.control.TextArea
        ProcessingModeButtonGroup_2     matlab.ui.container.ButtonGroup
        PhasorplotsonlyButton_2         matlab.ui.control.RadioButton
        PhasortoCellButton_2            matlab.ui.control.RadioButton
        NoofROIDropDown_2Label          matlab.ui.control.Label
        NoofROIDropDown_2               matlab.ui.control.DropDown
        PointsROIDropDown_2Label        matlab.ui.control.Label
        PointsROIDropDown_2             matlab.ui.control.DropDown
        BrowseButton_12                 matlab.ui.control.Button
        SaveinEditField_2Label_2        matlab.ui.control.Label
        SaveinEditField_MAcq            matlab.ui.control.EditField
        SingleTab                       matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        LaserSettingsTab                matlab.ui.container.Tab
        MHzLabel                        matlab.ui.control.Label
        RepetitionRateEditFieldLabel    matlab.ui.control.Label
        RepetitionRateEditField         matlab.ui.control.NumericEditField
        SameasMultiacquisitionCheckBox  matlab.ui.control.CheckBox
        CellImageTab                    matlab.ui.container.Tab
        BrowseButton                    matlab.ui.control.Button
        BrowseButton_2                  matlab.ui.control.Button
        nsLabel                         matlab.ui.control.Label
        CellImagesdtEditFieldLabel      matlab.ui.control.Label
        CellImagesdtEditField           matlab.ui.control.EditField
        CellMasktifEditField            matlab.ui.control.EditField
        photonspxLabel                  matlab.ui.control.Label
        FilenamesdtButtonGroup          matlab.ui.container.ButtonGroup
        MasktifButton                   matlab.ui.control.RadioButton
        IntensityButton                 matlab.ui.control.RadioButton
        EditField                       matlab.ui.control.EditField
        LifetimeRangeEditFieldLabel     matlab.ui.control.Label
        LifetimeRangeEditField          matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        toEditField                     matlab.ui.control.NumericEditField
        toEditField_2Label              matlab.ui.control.Label
        tointensityEditField_2          matlab.ui.control.NumericEditField
        OrmaskbyintensityEditField      matlab.ui.control.NumericEditField
        SameasMultiacquisitionCheckBox_3  matlab.ui.control.CheckBox
        IRFTab                          matlab.ui.container.Tab
        BrowseButton_3                  matlab.ui.control.Button
        nsLabel_2                       matlab.ui.control.Label
        IRFImagesdtEditFieldLabel       matlab.ui.control.Label
        IRFImagesdtEditField            matlab.ui.control.EditField
        KnownLifetimeLabel              matlab.ui.control.Label
        KnownLifetimeEditField          matlab.ui.control.NumericEditField
        SameasMultiacquisitionCheckBox_2  matlab.ui.control.CheckBox
        CommandsTextAreaLabel           matlab.ui.control.Label
        CommandsTextArea                matlab.ui.control.TextArea
        StartButton                     matlab.ui.control.StateButton
        ProcessingModeButtonGroup       matlab.ui.container.ButtonGroup
        PhasorplotsonlyButton           matlab.ui.control.RadioButton
        PhasortoCellButton              matlab.ui.control.RadioButton
        CelltoPhasorButton              matlab.ui.control.RadioButton
        NoofROIDropDownLabel            matlab.ui.control.Label
        NoofROIDropDown                 matlab.ui.control.DropDown
        PointsROIDropDownLabel          matlab.ui.control.Label
        PointsROIDropDown               matlab.ui.control.DropDown
        BrowseButton_11                 matlab.ui.control.Button
        SaveinEditField_2Label          matlab.ui.control.Label
        SaveinEditField_2               matlab.ui.control.EditField
        CellSegmentationTab             matlab.ui.container.Tab
        BrowseButton_9                  matlab.ui.control.Button
        CellImagesdtEditField_2Label    matlab.ui.control.Label
        CellImagesdtEditField_2         matlab.ui.control.EditField
        PointsMaskDropDownLabel         matlab.ui.control.Label
        PointsMaskDropDown              matlab.ui.control.DropDown
        SaveinEditFieldLabel            matlab.ui.control.Label
        SaveinEditField                 matlab.ui.control.EditField
        BrowseButton_10                 matlab.ui.control.Button
        StartButton_3                   matlab.ui.control.StateButton
        CommandsTextArea_3Label         matlab.ui.control.Label
        CommandsTextArea_CellSeg        matlab.ui.control.TextArea
        FileNamesdtEditFieldLabel       matlab.ui.control.Label
        FileNamesdtEditField            matlab.ui.control.EditField
        RightPanel                      matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        PhasorPlot                      matlab.ui.control.UIAxes
        ModulationLifetime              matlab.ui.control.UIAxes
        PhaseLifetime                   matlab.ui.control.UIAxes
        Cbar                            matlab.ui.control.UIAxes
        CellImageDropDownLabel          matlab.ui.control.Label
        CellImageDropDown               matlab.ui.control.DropDown
        TabulatedLifetimes              matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        AverageLifetimesLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Property % Description
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function PhasorPlotOriImageClicked(app, event)
            %            PhasorPlotOriImageClick
        end

        % Callback function
        function RepetitionRateEditFieldValueChanged(app, event)
            %   f = app.RepetitionRateEditField.Value*1E6;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filenameTCSPC, pathname]=uigetfile({'*.sdt'},'Select TCSPC cell image file (.sdt)');
            fullpathname_TCSPC=strcat(pathname,filenameTCSPC);
            %test=fileread(fullpathname_TCSPC);
            app.CellImagesdtEditField.Value=fullpathname_TCSPC;
            app.EditField.Value=strcat(filenameTCSPC(1:end-4));
        end

        % Value changed function: CellImagesdtEditField
        function CellImagesdtEditFieldValueChanged(app, event)
            %
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [filenameMASK, pathname]=uigetfile({'*.tif'},'Select cell mask file (.tif)');
            fullpathname_mask=strcat(pathname,filenameMASK);
            %test=fileread(fullpathname_TCSPC);
            app.CellMasktifEditField.Value=fullpathname_mask;
        end

        % Callback function
        function LifetimeRangeEditFieldValueChanged(app, event)
            %   CLIM_low = app.LifetimeRangeEditField.Value;
        end

        % Callback function
        function toEditFieldValueChanged(app, event)
            %    CLIM_high = app.toEditField.Value;
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            [filenameIRF, pathname]=uigetfile({'*.sdt'},'Select IRF file (.sdt)');
            fullpathname_IRF=strcat(pathname,filenameIRF);
            %test=fileread(fullpathname_TCSPC);
            app.IRFImagesdtEditField.Value=fullpathname_IRF;
        end

        % Callback function
        function KnownLifetimeEditFieldValueChanged(app, event)
            %    ddRefTau = app.KnownLifetimeEditField.Value;
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            %%%% Single acquisition
            
            app.CommandsTextArea.Value='Processing...';
            
            CLIM_low=app.LifetimeRangeEditField.Value*1E-9;
            CLIM_high=app.toEditField.Value*1E-9;
            
            f = app.RepetitionRateEditField.Value*1E6; %SYSTEM: Laser repetition frequency/Hz
            w = 2*pi*f; %SYSTEM: Laser repetition rate(angular speed)
            t_chan = 50/(1E9*256); %Time interval of each time bin (gain:1)
            t_serie = ((0:255).*t_chan)';
            
            flagOffsetComp_dd=1; %SYSTEM: choose 1 to calculate the intensity offset (baseline)
            % of FLIM data according to the first "num_offset" data points
            % of a decay curve, and subtract this number from all time bins in
            % the decay curve (the negative values in the results will be changed to 0).
            flagOffsetCompRef=1;%DEFAULT: choose 1 to compensate intensity offset for standard reference data
            num_offset=9;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %INTENSITY FILTERS AND BINNING SETUP
            % Intensity filters and binning settings for donor channel data
            %pmin_dd = 150; %SYSTEM: threshold for minimum photon counts for the FLIM image
            %pmax_dd = 10000000000; %SYSTEM: threshold for maximum photon counts for the FLIM image
            pminRef_dd = 150; %SYSTEM: threshold for minimum photon counts for the standard sample data
            binIm_dd = 9; %SYSTEM: binning value for the sample data.Can only be ODD number or 256.
            binRef_dd = 256; %SYSTEM: binning value for the standard sample data.Can only be ODD number or 256.
            maxfilterlength=80000;
            %% Setting up IRF calibration
            %DIRECTORY: Standard sample donor channel file location
            ddRefSdt=app.IRFImagesdtEditField.Value;
            %app.CommandsTextArea.Value=ddRefSdt;
            ddRefTau=app.KnownLifetimeEditField.Value*1E-9; %SYSTEM: lifetime of standard sample measured in donor channel
            %CALCULATIONS FOR IRF CALIBRATION PARAMETERS
            mRef_dd = (1 + (w*ddRefTau).^2).^(-0.5); % Modulation value that the donor channel standard sample should have.
            phiRef_dd = atan(w*ddRefTau); % Phase value that the donor channel standard sample should have.
            
            %IRF CALIBRATION
            % Import data.
            % Note that the function "loadBHfileusingmeasDescBlock" for
            % importing Becker & Hickl FLIM data is written by Dr. Sean Warren.
            ddref = loadBHfileusingmeasDescBlock(ddRefSdt);
            ddref=permute(ddref,[3,2,1]);
            % dataMatrix(y dimension, x dimension, time dimension)
            ddref=double(ddref);
            
            % Intensity Matrix of the sample
            inten_Ref_dd = sum(ddref,3);
            
            % Create intensity mask
            mask_Ref_dd = inten_Ref_dd>pminRef_dd;
            mask_Ref_dd = repmat(mask_Ref_dd,[1,1,256]);
            ref_mask_dd = ddref.*mask_Ref_dd;
            
            
            % Binning the image
            ref2_dd=convn(ref_mask_dd, ones(binRef_dd),'valid');
            
            % Filter out intensity 0 points (including points filtered out by the
            % intensity mask) before doing the Fourier transform
            filter_Ref_dd = find(sum(ref2_dd,3));
            ref3_dd = reshape(ref2_dd,[],256);
            ref3_dd = ref3_dd';
            [sizeFilterRef_dd,test] = size(filter_Ref_dd);
            
            if flagOffsetCompRef==1
                %Subtracting the offset of the decay curve
                ref3_dd=ref3_dd-repmat(mean(ref3_dd((1:num_offset),:)),256,1);
                %Assign negative values 0 value instead.
                ref3_dd=ref3_dd-ref3_dd.*(ref3_dd<0);
            end
            intenRef_dd = sum(ref3_dd);
            
            % Calculate the phasor representation of the raw data of the standard sample
            gRef_dd = (sum(ref3_dd(:,filter_Ref_dd).*repmat(cos(w.*t_serie),[1,sizeFilterRef_dd]))./intenRef_dd(filter_Ref_dd));
            sRef_dd = (sum(ref3_dd(:,filter_Ref_dd).*repmat(sin(w.*t_serie),[1,sizeFilterRef_dd]))./intenRef_dd(filter_Ref_dd));
            
            % Calculate the averaged phasor parameters of the standard sample raw data
            GRef_dd = mean(gRef_dd);
            SRef_dd = mean(sRef_dd);
            
            % Calculate the modulation and phase correction from the standard sample
            mCor_dd = mRef_dd./(sqrt(GRef_dd.^2+SRef_dd.^2));
            phiCor_dd = phiRef_dd - atan2(SRef_dd,GRef_dd);
            
            
            %% Creating phase and modulation lifetime images to select ROIs
            % DIRECTORY
            % ddSdt= sprintf('/Users/chungchyiwei/OneDrive - University of Cambridge/First Year/Codes/20200310_FPT_FLIM_edit1/files/%s/sdtfiles/%s_%d.sdt',name,name,k);
            ddSdt=app.CellImagesdtEditField.Value;
            %Importing TCSPC image
            dataMatrix_dd= loadBHfileusingmeasDescBlock(ddSdt);
            dataMatrix_dd = permute(dataMatrix_dd,[3,2,1]);
            dataMatrix_dd = double(dataMatrix_dd);
            intenMatrix_dd = sum(dataMatrix_dd,3);


            if (app.MasktifButton.Value)
                %Importing tif masks
                raw_data1=imread(app.CellMasktifEditField.Value);
                raw_data2=single(raw_data1);
                %unhash for checking purposes
                %imagesc(raw_data2);
                %title('Imported .tif mask (yellows - 1, purple - 0');
                
                raw_data=repmat(raw_data2,[1,1,256]);
                mask_P_dd=logical(raw_data);
            end
            
            
            if (app.IntensityButton.Value)
                pmin_dd=app.OrmaskbyintensityEditField.Value;
                pmax_dd=app.tointensityEditField_2.Value;
                mask_P_dd = (intenMatrix_dd>pmin_dd)&(intenMatrix_dd<pmax_dd);
                mask_P_dd = repmat(mask_P_dd,[1,1,256]);
            end
            
            dataMatrix2_dd = dataMatrix_dd.*mask_P_dd;
            
            % Binning the image
            dataMatrix2_dd = convn(dataMatrix2_dd, ones(binIm_dd),'valid');
            dataMatrix3_dd = reshape(dataMatrix2_dd,[],256);
            dataMatrix3_dd = dataMatrix3_dd';
            
            %Subtract the intensity offset from the decay curve:
            if flagOffsetComp_dd==1
                dataMatrix3_dd=dataMatrix3_dd-repmat(mean(dataMatrix3_dd((1:num_offset),:)),256,1);
                dataMatrix3_dd=dataMatrix3_dd-dataMatrix3_dd.*(dataMatrix3_dd<0);
            end
            
            intenData_dd = sum(dataMatrix3_dd);
            filter = (find(intenData_dd))'; filterlength=length(filter);
            [sizeFilter,test] = size(filter);
            
            % Calculate the phasor representation of sample
            g_dd = (sum(dataMatrix3_dd(:,filter).*repmat(cos(w.*t_serie),[1,sizeFilter]))./intenData_dd(filter));
            s_dd = (sum(dataMatrix3_dd(:,filter).*repmat(sin(w.*t_serie),[1,sizeFilter]))./intenData_dd(filter));
            
            % IRF correction
            G_dd = (g_dd.*cos(phiCor_dd) - s_dd.*sin(phiCor_dd)).*mCor_dd;
            S_dd = (g_dd.*sin(phiCor_dd) + s_dd.*cos(phiCor_dd)).*mCor_dd;
            G_ddsumORI=G_dd; S_ddsumORI=S_dd;
            
            count=1; %Single image by default
            %% Creating lifetime figures
            %Phase lifetime check
            tauphaseORI=w^-1.*(S_ddsumORI(count,:)./G_ddsumORI(count,:));
            tauphaseORI(tauphaseORI==0)=nan;
            meantauphaseORI=nanmean(tauphaseORI,2);
            
            %Mod lifetime check
            phi=atan(S_ddsumORI(count,:)./G_ddsumORI(count,:));
            M1=G_ddsumORI(count,:)./cos(phi);
            %M2=S_ddsum(count,:)./sin(phi); %Should be the same as M1
            
            taumodORI=sqrt(1./M1.^2 -1)./w;
            taumodORI(taumodORI==0)=nan;
            meantaumodORI=nanmean(taumodORI,2);
            
            
            savelocation=string(app.SaveinEditField_2.Value);
            
            type=["Mod","Phase"];
            stage=["Ori","ROI"];
            % Modulation lifetime
            [subx,suby]=ind2sub([257-binIm_dd,257-binIm_dd],filter);
            fracMol_dIn=taumodORI(:,1:length(filter));
            filenameOFFICIAL=app.EditField.Value;
            filenamestring=string(filenameOFFICIAL);
            %             app.CommandsTextArea.Value=filenamestring;
            
            [figmod, figmodname,Lifetimemodfigmat]=lifetimefigs_celltophasor(subx,suby,fracMol_dIn,binIm_dd,mask_P_dd,dataMatrix_dd,CLIM_low,CLIM_high,filenamestring,type(1),stage(1),savelocation);
            %lifetimefigs_edit1(subx,suby,fracMol_dIn,binIm_dd,mask_P_dd,dataMatrix_dd,CLIM_d);
            close all;
            % Phase lifetime
            [subx,suby]=ind2sub([257-binIm_dd,257-binIm_dd],filter);
            fracMol_dIn=tauphaseORI(:,1:length(filter));
            [figphase, figphasename, Lifetimephasefigmat,sub_Mat]=lifetimefigs_celltophasor(subx,suby,fracMol_dIn,binIm_dd,mask_P_dd,dataMatrix_dd,CLIM_low,CLIM_high,filenamestring,type(2),stage(1),savelocation);
            %lifetimefigs_edit1(subx,suby,fracMol_dIn,binIm_dd,mask_P_dd,dataMatrix_dd,CLIM_d,Rownames,n,dataname,dn,k,count,type(2));
            %             hold off;
            close all;
            
            %             app.CommandsTextArea.Value=figmodname;


            %% Saving phasor values from original plot
            n=1;
            G_ddsum1ORI = matsum(G_ddsumORI,n);
            S_ddsum1ORI = matsum(S_ddsumORI,n);
            
            G_ddsumlogiORI=logical(G_ddsumORI);
            S_ddsumlogiORI=logical(S_ddsumORI);
            
            G_ddnnzORI=matsum(G_ddsumlogiORI,n);
            S_ddnnzORI=matsum(S_ddsumlogiORI,n);
            
            G_ddsum2ORI=G_ddsum1ORI./G_ddnnzORI;G_ddsum2ORI(G_ddsum2ORI==0)=nan;
            S_ddsum2ORI=S_ddsum1ORI./S_ddnnzORI;S_ddsum2ORI(S_ddsum2ORI==0)=nan;
            
            %% MAKING PHASOR PLOTS
            %Plotting universal semicircle
            
            bcirc=linspace(0,1,1000);
            acirc=sqrt(0.25-(bcirc-0.5).^2);
            
            ax = app.PhasorPlot;
            cla(ax);
            p(1)=plot(ax,bcirc,acirc,'k','LineWidth',2);hold(ax,'on');
            
            %     set(gca,'Fontsize',15);
            xlabel(ax,'G');
            ylabel(ax,'S')
            %Plotting monoexponential lifetimes on semicircle
            tau_unisem=0:1e-9:6e-9;tau_unisem1=tau_unisem*1e9;
            g_unisem=1./(1+w^2*tau_unisem.^2);
            s_unisem=w*tau_unisem./(1+w^2*tau_unisem.^2);
            p(2)=plot(ax,g_unisem,s_unisem,'ro','MarkerSize',8);
            
            % To add data labels to points on the graph
            for q=1:length(tau_unisem)
                text(ax,g_unisem(1,q)+0.025,s_unisem(1,q),sprintf('%g %s',tau_unisem1(q),"ns"));
            end
            
            % Plotting original phasor
            G_ddsum3ORI=G_ddsum2ORI(1,:);
            G_ddsum3ORI = G_ddsum3ORI(~isnan(G_ddsum3ORI));
            
            S_ddsum3ORI=S_ddsum2ORI(1,:);
            S_ddsum3ORI = S_ddsum3ORI(~isnan(S_ddsum3ORI));
            
            %Scatter Plotting
            X=G_ddsum3ORI';Y=S_ddsum3ORI';
            minx = min(X);
            maxx = max(X);
            miny = min(Y);
            maxy = max(Y);
            lambda = [];
            nbins = [];
            msize = 5;
            marker = 's';
            if isempty(nbins)
                nbins = [min(numel(unique(X)),200) ,min(numel(unique(Y)),200) ];
            end
            if isempty(lambda)
                lambda = 40;
            end
            edges1 = linspace(minx, maxx, nbins(1)+1);
            ctrs1 = edges1(1:end-1) + .5*diff(edges1);
            edges1 = [-Inf edges1(2:end-1) Inf];
            edges2 = linspace(miny, maxy, nbins(2)+1);
            ctrs2 = edges2(1:end-1) + .5*diff(edges2);
            edges2 = [-Inf edges2(2:end-1) Inf];
            [n,p] = size(X);
            bin = zeros(n,2);
            % Reverse the columns to put the first column of X along the horizontal
            % axis, the second along the vertical.
            [dum,bin(:,2)] = histc(X,edges1);
            [dum,bin(:,1)] = histc(Y,edges2);
            
            nbins([2 1]);
            H = accumarray(bin,1,nbins([2 1])) ./ n;
            G = smooth1D(H,nbins(2)/lambda);
            F = smooth1D(G',nbins(1)/lambda)';
            F = F./max(F(:));
            ind = sub2ind(size(F),bin(:,1),bin(:,2));
            col = F(ind);
            p(3) = scatter(ax,X,Y,msize,col,marker);
            
            
            legend(ax,'Universal semicircle','Mono Exp Lifetimes','Phasor Plot','Location','southeast');
            title(ax,'Original phasor plot');
            
            figname=sprintf('%s_Phasorplot.tif',filenamestring);
            figname=fullfile(savelocation,figname);
            %             saveas(ax,figname);
            hold(ax,'off');
            
            %Displaying phase lifetime image
            hold(app.PhaseLifetime,'on');
            cla(app.PhaseLifetime);
            Iphase=imread(figphasename);
            imshow(Iphase,'Parent',app.PhaseLifetime);
            hold(app.PhaseLifetime,'off');
            
            %Displaying modulation lifetime image
            hold(app.ModulationLifetime,'on');
            cla(app.ModulationLifetime);
            Imod=imread(figmodname);
            imshow(Imod,'Parent',app.ModulationLifetime);
            hold(app.ModulationLifetime,'off');
            
            [cbar,cbarname]=colourbar_saveloc(CLIM_low,CLIM_high,savelocation);
%             close all;
            cla(app.Cbar);
            colourbar1=imread(cbarname);
            hold(app.Cbar,'on');
            imshow(colourbar1,'Parent',app.Cbar);
            hold(app.Cbar,'off');
%             close all;
            
            
            %Table2
            fig=app.UITable;
            uit=uitable(fig);%'Data',tdata);
            Rownames=filenamestring;
            uit.Data=[Rownames, meantaumodORI*1e9,meantauphaseORI*1e9];
            %Dropdown box
            app.CellImageDropDown.Items=filenamestring;
            
            if (app.CelltoPhasorButton.Value)
                app.CommandsTextArea.Value='Thank you. Executing cell to phasor analysis. Please wait...';


                %% Prompting user to choose ROI
                close all;
                numROI=app.NoofROIDropDown.Value;numROI=str2double(numROI);
                %app.CommandsTextArea.Value=string(numROI);
                ROIpts=app.PointsROIDropDown.Value;ROIpts=str2double(ROIpts);
                %app.CommandsTextArea.Value=string(ROIpts);
                
                x=zeros(1,ROIpts);
                y=zeros(1,ROIpts);
                xstore=zeros(numROI,ROIpts);
                ystore=zeros(numROI,ROIpts);
                G_ddsum=zeros(numROI,maxfilterlength);
                S_ddsum=zeros(numROI,maxfilterlength);
                close all;
                app.CommandsTextArea.Value=['You have defined ' string(ROIpts) ' points for ' string(numROI) ' ROI. Please define the ROI on the modulation lifetime cell image.' ];


                for jj=1:numROI
                    if jj==1
                        I = imread(figmodname); % example
                        %                 labels1=linspace(CLIM_low,CLIM_high,3)*1E9;
                        %                 labels=string(labels1);
                        %                 h=colorbar;
                        %                 yTickNumbers = linspace(0,1,3);
                        
                        imshow(I,'Parent',app.ModulationLifetime);%set(h,'YTick',yTickNumbers,'YTickLabel',labels);
                        close all;figure(1);imshow(I);title('Please select a ROI.');
                        [x,y]=ginput(ROIpts);
                        %disp('Thank you. Please wait.');
                        xstore(jj,:)=x;
                        ystore(jj,:)=y;
                        
                    else
                        x=xstore(1,:);
                        y=ystore(1,:);
                        nColumns = size(x,2);
                        AB = [x,y]';
                        AB = reshape(AB(:),nColumns,[])';
                        for l=1:nColumns
                            AB_New(1,2*l-1:2*l)=AB(:,l);
                        end
                        I = imread(figmodname);
                        I = insertShape(I,'FilledPolygon',{AB_New(1,:)},'Color', {'magenta'},'Opacity',0.5);
                        figure(1);imshow(I);title('Please select the second ROI.');
                        [x,y]=ginput(ROIpts);
                        %disp('Thank you. Please wait.');
                        xstore(jj,:)=x;
                        ystore(jj,:)=y;
                    end
                    
                    %Creating a logical mask
                    raw_data1=zeros(256,256);
                    raw_data1(1:248,1:248) = roipoly(I,xstore(jj,:),ystore(jj,:));
                    raw_data1=logical(raw_data1);
                    mask_P_dd=raw_data1;
                    
                    %% Creating lifetime images with drawn ROI mask
                    
                    dataMatrix2_dd = dataMatrix_dd.*mask_P_dd;
                    
                    % Binning the image
                    dataMatrix2_dd = convn(dataMatrix2_dd, ones(binIm_dd),'valid');
                    dataMatrix3_dd = reshape(dataMatrix2_dd,[],256);
                    dataMatrix3_dd = dataMatrix3_dd';
                    
                    %Subtract the intensity offset from the decay curve:
                    if flagOffsetComp_dd==1
                        dataMatrix3_dd=dataMatrix3_dd-repmat(mean(dataMatrix3_dd((1:num_offset),:)),256,1);
                        dataMatrix3_dd=dataMatrix3_dd-dataMatrix3_dd.*(dataMatrix3_dd<0);
                    end
                    
                    intenData_dd = sum(dataMatrix3_dd);
                    filter = (find(intenData_dd))'; filterlength=length(filter);
                    [sizeFilter,test] = size(filter);
                    
                    % Calculate the phasor representation of sample
                    g_dd = (sum(dataMatrix3_dd(:,filter).*repmat(cos(w.*t_serie),[1,sizeFilter]))./intenData_dd(filter));
                    s_dd = (sum(dataMatrix3_dd(:,filter).*repmat(sin(w.*t_serie),[1,sizeFilter]))./intenData_dd(filter));
                    
                    % IRF correction
                    G_dd = (g_dd.*cos(phiCor_dd) - s_dd.*sin(phiCor_dd)).*mCor_dd;
                    S_dd = (g_dd.*sin(phiCor_dd) + s_dd.*cos(phiCor_dd)).*mCor_dd;
                    G_ddsum(jj,:)=[G_dd,zeros(1,(maxfilterlength-length(G_dd)))];
                    S_ddsum(jj,:)=[S_dd,zeros(1,(maxfilterlength-length(S_dd)))];
                    
                    
                    if jj==1 && numROI==2
                        app.CommandsTextArea.Value='Please select the second ROI';
                    else
                    end
                end
                
                
                for jj=1:numROI
                    x=xstore(jj,:);
                    y=ystore(jj,:);
                    nColumns = size(x,2);
                    AB = [x,y]';
                    AB = reshape(AB(:),nColumns,[])';
                    for l=1:nColumns
                        AB_New(jj,2*l-1:2*l)=AB(:,l);
                    end
                    
                end
                
                
                I = imread(figmodname);
                if numROI==2
                    I = insertShape(I,'FilledPolygon',{AB_New(1,:),AB_New(2,:)},'Color', {'magenta','green'},'Opacity',0.5);
                    figure(1);imshow(I);title('Thank you for completing your selection');
                    
                else
                    I = insertShape(I,'FilledPolygon',AB_New(1,:),'Color', 'magenta','Opacity',0.5);
                    figure(1);imshow(I);title('Thank you for completing your selection');
                    
                end
                
                app.CommandsTextArea.Value='Thank you for making your selection. Please find the results from this Cell to Phasor analysis.';
                %                 fig1=figure(1);close(fig1);
                close all;
                
                %% Outputing results
                if numROI==1
                    
                    % Plotting updated phasor
                    ax=app.PhasorPlot; hold(ax,'on');close all;
                    
                    
                    G_ddsum3=G_ddsum(jj,:);
                    G_ddsum3 = G_ddsum3(~isnan(G_ddsum3));
                    
                    S_ddsum3=S_ddsum(jj,:);
                    S_ddsum3 =S_ddsum3(~isnan(S_ddsum3));
                    
                    facecolour=[[1 0 1]; %green
                        0 1 0]; %lavender
                    p(4)=scatter(ax,G_ddsum3',S_ddsum3',1,facecolour(1,:),'x');drawnow;
                    
                    legend(ax,p(4),'ROI_1','Location','southeast');
                    
                    title(ax,'Phasor plot with selected ROI');
                    figname=sprintf('%s_Phasorplot_Cell2Phasor.tif',filenamestring);
                    figname=fullfile(savelocation,figname);
                    %                     saveas(ax,figname);
                    hold(ax,'off');


                    % Plotting updated modulation lifetime image
                    ImodNEW = imread(figmodname);
                    
                    ImodNEW = insertShape(ImodNEW,'FilledPolygon',AB_New(1,:),'Color', 'magenta','Opacity',0.5);
                    
                    position = AB_New(:,1:2);
                    num="1";
                    ImodNEW = insertText(ImodNEW,position,num,'AnchorPoint','LeftBottom','BoxColor','m','BoxOpacity',0.1,'TextColor','white');
                    ModLifetimeImgNEW=app.ModulationLifetime;
                    hold(ModLifetimeImgNEW,'on');
                    imshow(ImodNEW,'Parent',ModLifetimeImgNEW),title(ModLifetimeImgNEW,'Modulation Lifetime');drawnow;
                    figname=sprintf('%s_%s_Cell2Phasor.tif',filenamestring,"Mod");
                    figname=fullfile(savelocation,figname);
                    imwrite(ImodNEW,figname);
                    hold(ModLifetimeImgNEW,'off');
                    
                    % Plotting updated phase lifetime image
                    IphaseNEW = imread(figphasename);
                    
                    IphaseNEW = insertShape(IphaseNEW,'FilledPolygon',AB_New(1,:),'Color', 'magenta','Opacity',0.5);
                    
                    position = AB_New(:,1:2);
                    num="1";
                    IphaseNEW = insertText(IphaseNEW,position,num,'AnchorPoint','LeftBottom','BoxColor','m','BoxOpacity',0.1,'TextColor','white');
                    PhaseLifetimeImgNEW=app.PhaseLifetime;
                    hold(PhaseLifetimeImgNEW,'on');
                    imshow(IphaseNEW,'Parent',PhaseLifetimeImgNEW);
                    title(PhaseLifetimeImgNEW,'Phase Lifetime');drawnow;
                    figname=sprintf('%s_%s_Cell2Phasor.tif',filenamestring,"Phase");
                    figname=fullfile(savelocation,figname);
                    imwrite(IphaseNEW,figname);
                    hold(PhaseLifetimeImgNEW,'off');


                else
                    
                    ax=app.PhasorPlot; hold(ax,'on');close all;
                    for jj=1:numROI
                        G_ddsum3=G_ddsum(jj,:);
                        G_ddsum3 = G_ddsum3(~isnan(G_ddsum3));
                        
                        S_ddsum3=S_ddsum(jj,:);
                        S_ddsum3 =S_ddsum3(~isnan(S_ddsum3));
                        
                        facecolour=[[1 0 1]; %green
                            0 1 0]; %lavender
                        p(jj+3)=scatter(ax,G_ddsum3',S_ddsum3',1,facecolour(jj,:),'x');
                    end
                    legend(ax,[p(4),p(5)],'ROI_1','ROI_2','Location','southeast');
                    %set(gca,'xlim',phasor_xlim,'ylim',phasor_ylim,'xtick',phasor_xtick,'ytick',phasor_ytick);
                    %ylim(ax,phasor_ylim);xlim(ax,phasor_xlim);
                    title(ax,'Phasor plot with selected ROI');
                    figname=sprintf('%s_Phasorplot_Cell2Phasor.tif',filenamestring);
                    figname=fullfile(savelocation,figname);
                    %                     saveas(ax,figname);
                    hold(ax,'off');
                    
                    
                    % Plotting updated modulation lifetime image
                    ImodNEW = imread(figmodname);
                    for jj=1:numROI
                        x=xstore(jj,:);
                        y=ystore(jj,:);
                        nColumns = size(x,2);
                        AB = [x,y]';
                        AB = reshape(AB(:),nColumns,[])';
                        for l=1:nColumns
                            AB_New(jj,2*l-1:2*l)=AB(:,l);
                        end
                        
                    end
                    
                    ImodNEW = insertShape(ImodNEW,'FilledPolygon',{AB_New(1,:),AB_New(2,:)},'Color', {'magenta','green'},'Opacity',0.5);
                    
                    position = AB_New(:,1:2);
                    num=["1","2"];
                    ImodNEW = insertText(ImodNEW,position,num,'AnchorPoint','LeftBottom','BoxColor',{'m','g'},'BoxOpacity',0.1,'TextColor','white');
                    ModLifetimeImgNEW=app.ModulationLifetime;
                    hold(ModLifetimeImgNEW,'on');
                    imshow(ImodNEW,'Parent',ModLifetimeImgNEW);
                    title(ModLifetimeImgNEW,'Modulation Lifetime');drawnow;
                    figname=sprintf('%s_%s_Cell2Phasor.tif',filenamestring,"Mod");
                    figname=fullfile(savelocation,figname);
                    imwrite(ImodNEW,figname);
                    hold(ModLifetimeImgNEW,'off');
                    
                    % Plotting updated phase lifetime image
                    IphaseNEW = imread(figphasename);
                    for jj=1:numROI
                        x=xstore(jj,:);
                        y=ystore(jj,:);
                        nColumns = size(x,2);
                        AB = [x,y]';
                        AB = reshape(AB(:),nColumns,[])';
                        for l=1:nColumns
                            AB_New(jj,2*l-1:2*l)=AB(:,l);
                        end
                        
                    end
                    
                    IphaseNEW = insertShape(IphaseNEW,'FilledPolygon',{AB_New(1,:),AB_New(2,:)},'Color', {'magenta','green'},'Opacity',0.5);
                    
                    position = AB_New(:,1:2);
                    num=["1","2"];
                    IphaseNEW = insertText(IphaseNEW,position,num,'AnchorPoint','LeftBottom','BoxColor',{'m','g'},'BoxOpacity',0.1,'TextColor','white');
                    PhaseLifetimeImgNEW=app.PhaseLifetime;
                    hold(PhaseLifetimeImgNEW,'on');
                    imshow(IphaseNEW,'Parent',PhaseLifetimeImgNEW);
                    title(PhaseLifetimeImgNEW,'Phase Lifetime');drawnow;
                    figname=sprintf('%s_%s_Cell2Phasor.tif',filenamestring,"Phase");
                    figname=fullfile(savelocation,figname);
                    imwrite(IphaseNEW,figname);
                    hold(PhaseLifetimeImgNEW,'off');
                    
                end
                
                close all;
                
                %Dropdown box
                app.CellImageDropDown.Items=filenamestring;
                app.CellImageDropDown.ItemsData=1:n;
                
                %Phase lifetime check
                tauphase=w^-1.*(S_ddsum./G_ddsum);
                tauphase(tauphase==0)=nan;
                meantauphase=nanmean(tauphase,2);
                stdtauphase=nanstd(tauphase,0,2);
                meantauphase=[meantauphaseORI;meantauphase];%Adding original value
                
                %Mod lifetime check
                phi=atan(S_ddsum./G_ddsum);
                M1=G_ddsum./cos(phi);
                M2=S_ddsum./sin(phi);
                
                %for checking: should be the same as M1
                taumod=sqrt(1./M1.^2 -1)./w;
                taumod(taumod==0)=nan;
                meantaumod=nanmean(taumod,2);
                stdtaumod=nanstd(taumod,0,2);
                meantaumod=[meantaumodORI;meantaumod];%Adding original value
                
                
                %Table2
                fig=app.UITable;
                uit=uitable(fig);%'Data',tdata);
                Rownames=["Original";"ROI_1";"ROI_2"];Rownames=Rownames(1:numROI+1);
                uit.Data=[Rownames, meantaumod*1e9,meantauphase*1e9];
                
                close all;
            end
            
            
            %%% Single Acquisition: For phasor only
            
            if (app.PhasorplotsonlyButton.Value)
                
                app.CommandsTextArea.Value='Thank you. Your phasor plot analysis is completed.';


                close all;
                
                % app.CellImageDropDown.ItemsData=1:n;
            end %end of phasor only option
            
            %%% Single Image: Phasor to Cell
            
            if (app.PhasortoCellButton.Value)
                app.CommandsTextArea.Value='Thank you. Executing phasor to cell analysis. Please wait...';
                
                
                %% Prompting user to choose ROI
                close all;
                numROI=app.NoofROIDropDown.Value;numROI=str2double(numROI);
                %app.CommandsTextArea.Value=string(numROI);
                ROIpts=app.PointsROIDropDown.Value;ROIpts=str2double(ROIpts);
                %app.CommandsTextArea.Value=string(ROIpts);
                gstore=zeros(numROI,ROIpts);
                sstore=zeros(numROI,ROIpts);
                
                close all;
                % Creating phasor plot to mark ROI
                figure('Name','Phasor Plot','Visible','on'); hold on;
                
                p=plot(bcirc,acirc,'k','LineWidth',2);hold(ax,'on');
                xlabel('G');
                ylabel('S')
                %Plotting monoexponential lifetimes on semicircle
                p1=plot(g_unisem,s_unisem,'ro','MarkerSize',8);
                
                % To add data labels to points on the graph
                for q=1:length(tau_unisem)
                    text(g_unisem(1,q)+0.025,s_unisem(1,q),sprintf('%g %s',tau_unisem1(q),"ns"));
                end
                
                p3 = scatter(X,Y,msize,col,marker);
                meanG= mean(G_ddsum3ORI(1,:));
                meanS= mean(S_ddsum3ORI(1,:));
                stdG= std(G_ddsum3ORI(1,:));
                stdS= std(S_ddsum3ORI(1,:));
                p4=errorbar(meanG,meanS,stdS,stdS,stdG,stdG,'o','LineWidth',1.5,'color',rand(1,3));
                
                legend('Universal semicircle','Mono Exp Lifetimes','Phasor Plot','Mean and SD','Location','southeast');
                title('Please select your ROI');
                
                
                %                 app.CommandsTextArea.Value=['You have defined ' string(ROIpts) ' points for ' string(numROI) ' ROI. Please define the ROI on the phasor plot.'];
                %                 numROI=double(app.NoofROIDropDown_2.Value);
                %                 ROIpts=double(app.PointsROIDropDown_2.Value);
                
                app.CommandsTextArea.Value=['You have defined ' string(ROIpts) ' points for ' string(numROI) ' ROI. Please define the ROI on the phasor plot.' ];
                
                
                for jj=1:numROI
                    [g,s]=ginput(ROIpts);
                    %                     if jj==1 && numROI==2
                    %                         app.CommandsTextArea.Value='Thank you. Please define the first ROI';
                    %                         title('Please select ROI_1');
                    
                    
                    gstore(jj,:)=g;
                    sstore(jj,:)=s;
                    hold on;
                    
                    if numROI==1
                        facecolour=[0 0 1];
                    else
                        facecolour=[[1 0 1]; %lavender
                            0 1 0]; %%green
                    end
                    p(4)=plot(polyshape(gstore(jj,:),sstore(jj,:)),'FaceColor',facecolour(jj,:));
                    legend(p(4),'ROI_1');
                    
                    if jj==2 && numROI==2
                        app.CommandsTextArea.Value='Thank you. Please define the second ROI';title('Please selected ROI_2');
                    end
                    
                    hold off;
                    
                    %                     G_ddsum=G_ddsumORI;
                    %                     S_ddsum=S_ddsumORI;
                    
                    %                     count=1;
                    %                     while count<n+1
                    %                         %                             for q=1:datanum
                    %                         for h=1:n
                    %
                    %
                    count=1;
                    [in,on]=inpolygon(G_ddsumORI,S_ddsumORI,g,s);
                    
                    if in==0
                        xpos=0;
                    else
                        %Lifetime calculation
                        G_ddpoly=G_ddsumORI(count,:);S_ddpoly=S_ddsumORI(count,:);
                        G_ddnew=G_ddpoly(in);S_ddnew=S_ddpoly(in);
                        
                        %Phase lifetime check
                        tauphase2=w^-1.*(S_ddnew./G_ddnew);
                        %                                 tauphase2mean(jj,h)=nanmean(tauphase2,2);
                        
                        %Mod lifetime check
                        phi=atan(S_ddnew./G_ddnew);
                        M1=G_ddnew./cos(phi);
                        %M2=S_ddsum(count,:)./sin(phi);
                        
                        %for checking: should be the same as M1
                        taumod2=sqrt(1./M1.^2 -1)./w;
                        taumod2 = round(taumod2,12);
                        
                        %                                 taumod2mean(jj,h)=nanmean(taumod2,2);
                        
                        Lifetimematmod=Lifetimemodfigmat;
                        Lifetimematmod=round(Lifetimematmod,12);
                        
                        %search position on the cell image
                        xpos=zeros(100,length(taumod2));
                        ypos=zeros(100,length(taumod2));
                        for k=1:length(taumod2)
                            [findx,findy]=find(Lifetimematmod==taumod2(k));
                            lfind=length(findx);
                            for kk=1:lfind
                                
                                xpos(kk,k) = findx(kk);
                                ypos(kk,k) = findy(kk);
                            end
                        end
                        
                        %Modulation lifetime
                        Lifetimematmod1=Lifetimematmod;
                    end
                    colourval=[1,1];
                    if xpos== 0
                        Lifetimematmod1=zeros(size(Lifetimemodfigmat));
                    else
                        for k=1:length(xpos(:,1)) %rows in xpos
                            for kk=1:length(xpos(1,:)) %column in xpos
                                if xpos(k,kk)>0
                                    xpos1=xpos(k,kk);ypos1=ypos(k,kk);
                                    Lifetimematmod1(xpos1,ypos1)=colourval(jj);
                                elseif xpos(k,kk)==0
                                    Lifetimematmod1;
                                else
                                    Lifetimematmod1(xpos1,ypos1)=nan;
                                    
                                end
                            end
                        end
                    end
                    
                    
                    Lifetimematmod1(Lifetimematmod1<1)=nan;
                    colour=[10,64,30]; %64:red 30:green 10:blue
                    
                    
                    %                             imagename=sprintf('%s.sdt',name,h);
                    
                    %Importing TCSPC image
                    ddSdt=app.CellImagesdtEditField.Value;
                    dataMatrix_dd= loadBHfileusingmeasDescBlock(ddSdt);
                    dataMatrix_dd = permute(dataMatrix_dd,[3,2,1]);
                    dataMatrix_dd = double(dataMatrix_dd);
                    intenMatrix_dd = sum(dataMatrix_dd,3);
                    
                    
                    if (app.MasktifButton.Value)
                        %Importing tif masks
                        %                                 maskname=sprintf('%s_%d segmentation.tif',name,h);
                        maskfile=app.CellMasktifEditField.Value;
                        raw_data1=imread(maskfile);
                        raw_data2=single(raw_data1);
                        %unhash for checking purposes
                        %imagesc(raw_data2);
                        %title('Imported .tif mask (yellows - 1, purple - 0');
                        
                        raw_data=repmat(raw_data2,[1,1,256]);
                        mask_P_dd=logical(raw_data);
                        
                    end
                    
                    if (app.IntensityButton.Value)
                        pmin_dd=app.OrmaskbyintensityEditField.Value;
                        pmax_dd=app.tointensityEditField_2.Value;
                        mask_P_dd = (intenMatrix_dd>pmin_dd)&(intenMatrix_dd<pmax_dd);
                        mask_P_dd = repmat(mask_P_dd,[1,1,256]);
                    end
                    
                    %                             sub_Mat=submatstore(1+248*(count-1):248*count,1:248);
                    %                             dataname=app.EditField.Value;
                    [fig]=lifetimefigs_phasor_multiacq(binIm_dd,mask_P_dd,dataMatrix_dd,[0,1],filenamestring,1,type(1),Lifetimematmod1,colour(jj),[],jj,sub_Mat,savelocation);
                    
                end
                
                title('Thank you for making your selection.');
                legend('Universal semicircle','Mono Exp Lifetimes','Phasor Plot','Mean and SD','ROI_1','ROI_2','Location','southeast');
                hold off;
                app.CommandsTextArea.Value='Thank you for making your selection for this phasor to cell image analysis. Please wait for resulting images.';
                close all;
                
                % Figure 3: Phasor to Cell images
                figure('Name','Phasor to Cell Images');hold on;
                
                figA=sprintf('%s_%s__PHASORROI_%d.tif',filenamestring,type(1),1);
                figA=fullfile(savelocation,figA);
                figB=sprintf('%s_%s__PHASORROI_%d.tif',filenamestring,type(1),2);
                figB=fullfile(savelocation,figB);
                if numROI==2
                    %                             subplot(1,n,h);
                    figname=sprintf('%s_%s__PHASORROI_merged.tif',filenamestring,type(1));
                    figname=fullfile(savelocation,figname);
                    imwrite(imfuse(imread(figA),imread(figB),'ColorChannels',[2 1 2]),figname);
                    imshow(imfuse(imread(figA),imread(figB),'ColorChannels',[2 1 2]));
                else
                    %                             subplot(1,n,h);
                    imshow(imread(figA));
                end
                title(string(app.EditField.Value),'FontSize',6);
                
                hold off;
                
                %Updating figure on main window
                ax=app.PhaseLifetime;
                hold(ax,'on');
                %                 count=1;
                
                %                 for h=1:n
%                 h=1; %Plotting first figure by default
                figA=sprintf('%s_%s__PHASORROI_%d.tif',filenamestring,type(1),1);
                figA=fullfile(savelocation,figA);
                figB=sprintf('%s_%s__PHASORROI_%d.tif',filenamestring,type(1),2);
                figB=fullfile(savelocation,figB);
                if numROI==2
                    %                             subplot(1,n,h);
                    figname=sprintf('%s_%s__PHASORROI_merged.tif',filenamestring,type(1));
                    figname=fullfile(savelocation,figname);
                    imwrite(imfuse(imread(figA),imread(figB),'ColorChannels',[2 1 2]),figname);
                    imshow(imfuse(imread(figA),imread(figB),'ColorChannels',[2 1 2]),'Parent',ax);
                else
                    %                         subplot(1,n,h);
                    imshow(imread(figA),'Parent',ax);
                end
                title(ax,'Phasor to Cell');
                %                 end
                
                hold(ax,'off');


                %Updating phasor plot
                ax = app.PhasorPlot;
                hold(ax,'on');
                for jj=1:numROI
                    if numROI==1
                        facecolour=[0 0 1];
                    else
                        facecolour=[[1 0 1]; %lavender
                            0 1 0]; %%green
                    end
                    p(jj+3)=plot(ax,polyshape(gstore(jj,:),sstore(jj,:)),'FaceColor',facecolour(jj,:));
                end
                
                if numROI==1
                    legend(ax,p(4),'ROI_1');
                else
                    legend(ax,[p(4),p(5)],'ROI_1','ROI_2');
                end
                
                figname=sprintf('%s_Phasorplot_Phasor2Cell.tif',filenamestring);
                figname=fullfile(savelocation,figname);
                %                 saveas(ax,figname);
                hold(ax,'off');
                
            end
            
        end

        % Callback function
        function SwitchValueChanged(app, event)
            
        end

        % Callback function
        function MultiAcquisitionCheckBoxValueChanged(app, event)
            
        end

        % Value changed function: StartButton_2
        function StartButton_2ValueChanged(app, event)
            
            %%%% Multiacqusition
            savelocation=string(app.SaveinEditField_MAcq.Value);
            
            CLIM_low=double(app.LifetimeRangeEditField_2.Value)*1E-9;
            
            CLIM_high=double(app.toEditField_3.Value)*1E-9;
            
            CLIM_d=[CLIM_low,CLIM_high];
            f = double(app.RepetitionRateEditField_2.Value)*1E6; %SYSTEM: Laser repetition frequency/Hz
            w = double(2*pi*f); %SYSTEM: Laser repetition rate(angular speed)
            t_chan = 50/(1E9*256); %Time interval of each time bin (gain:1)
            t_serie = ((0:255).*t_chan)';
            
            flagOffsetComp_dd=1; %SYSTEM: choose 1 to calculate the intensity offset (baseline)
            % of FLIM data according to the first "num_offset" data points
            % of a decay curve, and subtract this number from all time bins in
            % the decay curve (the negative values in the results will be changed to 0).
            flagOffsetCompRef=1;%DEFAULT: choose 1 to compensate intensity offset for standard reference data
            num_offset=9;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %INTENSITY FILTERS AND BINNING SETUP
            % Intensity filters and binning settings for donor channel data
            %pmin_dd = 150; %SYSTEM: threshold for minimum photon counts for the FLIM image
            %pmax_dd = 10000000000; %SYSTEM: threshold for maximum photon counts for the FLIM image
            pminRef_dd = 150; %SYSTEM: threshold for minimum photon counts for the standard sample data
            binIm_dd = 9; %SYSTEM: binning value for the sample data.Can only be ODD number or 256.
            binRef_dd = 256; %SYSTEM: binning value for the standard sample data.Can only be ODD number or 256.
            maxfilterlength=80000;


            %% Setting up IRF calibration
            %DIRECTORY: Standard sample donor channel file location
            ddRefSdt=string(app.IRFImagesdtEditField_2.Value);
            %app.CommandsTextArea.Value=ddRefSdt;
            ddRefTau=double(app.KnownLifetimeEditField_2.Value)*1E-9; %SYSTEM: lifetime of standard sample measured in donor channel
            %CALCULATIONS FOR IRF CALIBRATION PARAMETERS
            mRef_dd = (1 + (w*ddRefTau).^2).^(-0.5); % Modulation value that the donor channel standard sample should have.
            phiRef_dd = atan(w*ddRefTau); % Phase value that the donor channel standard sample should have.
            
            %IRF CALIBRATION
            % Import data.
            % Note that the function "loadBHfileusingmeasDescBlock" for
            % importing Becker & Hickl FLIM data is written by Dr. Sean Warren.
            ddref = loadBHfileusingmeasDescBlock(ddRefSdt);
            ddref=permute(ddref,[3,2,1]);
            % dataMatrix(y dimension, x dimension, time dimension)
            ddref=double(ddref);
            
            % Intensity Matrix of the sample
            inten_Ref_dd = sum(ddref,3);
            
            % Create intensity mask
            mask_Ref_dd = inten_Ref_dd>pminRef_dd;
            mask_Ref_dd = repmat(mask_Ref_dd,[1,1,256]);
            ref_mask_dd = ddref.*mask_Ref_dd;
            
            
            % Binning the image
            ref2_dd=convn(ref_mask_dd, ones(binRef_dd),'valid');
            
            % Filter out intensity 0 points (including points filtered out by the
            % intensity mask) before doing the Fourier transform
            filter_Ref_dd = find(sum(ref2_dd,3));
            ref3_dd = reshape(ref2_dd,[],256);
            ref3_dd = ref3_dd';
            [sizeFilterRef_dd,test] = size(filter_Ref_dd);
            
            if flagOffsetCompRef==1
                %Subtracting the offset of the decay curve
                ref3_dd=ref3_dd-repmat(mean(ref3_dd((1:num_offset),:)),256,1);
                %Assign negative values 0 value instead.
                ref3_dd=ref3_dd-ref3_dd.*(ref3_dd<0);
            end
            intenRef_dd = sum(ref3_dd);
            
            % Calculate the phasor representation of the raw data of the standard sample
            gRef_dd = (sum(ref3_dd(:,filter_Ref_dd).*repmat(cos(w.*t_serie),[1,sizeFilterRef_dd]))./intenRef_dd(filter_Ref_dd));
            sRef_dd = (sum(ref3_dd(:,filter_Ref_dd).*repmat(sin(w.*t_serie),[1,sizeFilterRef_dd]))./intenRef_dd(filter_Ref_dd));
            
            % Calculate the averaged phasor parameters of the standard sample raw data
            GRef_dd = mean(gRef_dd);
            SRef_dd = mean(sRef_dd);
            
            % Calculate the modulation and phase correction from the standard sample
            mCor_dd = mRef_dd./(sqrt(GRef_dd.^2+SRef_dd.^2));
            phiCor_dd = phiRef_dd - atan2(SRef_dd,GRef_dd);
            
            dataname=string(app.ImageNameEditField.Value);
            n=double(app.NoofImagesEditField.Value);
            
            Rownames=zeros(n,1);Rownames=string(Rownames);
            
            for h=1:n
                Rownames(h)=sprintf('%s_%d',dataname,h);
                
            end
            
            % %             app.CommandsTextArea_2.Value=Rownames;
            close all;
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % FOR-LOOP 1: Creating a loop for different datasets (i.e. appear as different points on the phasor plot)
            n=double(app.NoofImagesEditField.Value);
            G_ddsum=zeros(n,maxfilterlength);
            S_ddsum=zeros(n,maxfilterlength);
            
            Lifetimemodfigmat=zeros(248*n,248);
            Lifetimephasefigmat=zeros(248*n,248);
            submatstore=zeros(248*n,248);
            
            name=dataname;
            for k = 1:n
                imagename=sprintf('%s_%d.sdt',name,k);
                
                %Importing TCSPC image
                ddSdt=fullfile(string(app.CellImageFolderEditField.Value),imagename);
                dataMatrix_dd= loadBHfileusingmeasDescBlock(ddSdt);
                dataMatrix_dd = permute(dataMatrix_dd,[3,2,1]);
                dataMatrix_dd = double(dataMatrix_dd);
                intenMatrix_dd = sum(dataMatrix_dd,3);
                
                
                if (app.MasktifButton_2.Value)
                    %Importing tif masks
                    maskname=sprintf('%s_%d segmentation.tif',name,k);
                    maskfile=fullfile(string(app.CellMasktifEditField_2.Value),maskname);
                    
                    raw_data1=imread(maskfile);
                    raw_data2=single(raw_data1);
                    %unhash for checking purposes
                    %imagesc(raw_data2);
                    %title('Imported .tif mask (yellows - 1, purple - 0');
                    
                    raw_data=repmat(raw_data2,[1,1,256]);
                    mask_P_dd=logical(raw_data);
                    
                end
                
                if (app.IntensityButton_2.Value)
                    pmin_dd=app.OrmaskbyintensityEditField_2.Value;
                    pmax_dd=app.tointensityEditField_3.Value;
                    mask_P_dd = (intenMatrix_dd>pmin_dd)&(intenMatrix_dd<pmax_dd);
                    mask_P_dd = repmat(mask_P_dd,[1,1,256]);
                end
                
                dataMatrix2_dd = dataMatrix_dd.*mask_P_dd;
                
                % Binning the image
                dataMatrix2_dd = convn(dataMatrix2_dd, ones(binIm_dd),'valid');
                dataMatrix3_dd = reshape(dataMatrix2_dd,[],256);
                dataMatrix3_dd = dataMatrix3_dd';
                
                %Subtract the intensity offset from the decay curve:
                if flagOffsetComp_dd==1
                    dataMatrix3_dd=dataMatrix3_dd-repmat(mean(dataMatrix3_dd((1:num_offset),:)),256,1);
                    dataMatrix3_dd=dataMatrix3_dd-dataMatrix3_dd.*(dataMatrix3_dd<0);
                end
                
                intenData_dd = sum(dataMatrix3_dd);
                filter = (find(intenData_dd))'; filterlength=length(filter);
                [sizeFilter,test] = size(filter);
                
                % Calculate the phasor representation of sample
                g_dd = (sum(dataMatrix3_dd(:,filter).*repmat(cos(w.*t_serie),[1,sizeFilter]))./intenData_dd(filter));
                s_dd = (sum(dataMatrix3_dd(:,filter).*repmat(sin(w.*t_serie),[1,sizeFilter]))./intenData_dd(filter));
                
                % IRF correction
                G_dd = (g_dd.*cos(phiCor_dd) - s_dd.*sin(phiCor_dd)).*mCor_dd;
                S_dd = (g_dd.*sin(phiCor_dd) + s_dd.*cos(phiCor_dd)).*mCor_dd;
                
                G_ddsum(k,:)=[G_dd,zeros(1,(maxfilterlength-length(G_dd)))];
                S_ddsum(k,:)=[S_dd,zeros(1,(maxfilterlength-length(S_dd)))];
                
                %% Creating lifetime figures
                %Phase lifetime check
                tauphase=w^-1.*(S_ddsum(k,:)./G_ddsum(k,:));
                tauphase(tauphase==0)=nan;
                
                %Mod lifetime check
                phi=atan(S_ddsum(k,:)./G_ddsum(k,:));
                M1=G_ddsum(k,:)./cos(phi);
                %M2=S_ddsum(k,:)./sin(phi); %Should be the same as M1
                
                taumod=sqrt(1./M1.^2 -1)./w;
                taumod(taumod==0)=nan;
                
                type=["Mod","Phase"];
                % Modulation lifetime
                [subx,suby]=ind2sub([257-binIm_dd,257-binIm_dd],filter);
                fracMol_dIn=taumod(:,1:length(filter));
                [fig1,Lifetimemodfigmat(1+248*(k-1):248*k,1:248),submatstore(1+248*(k-1):248*k,1:248)]=lifetimefigs(subx,suby,fracMol_dIn,binIm_dd,mask_P_dd,dataMatrix_dd,CLIM_d,dataname,1,k,type(1),savelocation);
                close all;
                % Phase lifetime
                [subx,suby]=ind2sub([257-binIm_dd,257-binIm_dd],filter);
                fracMol_dIn=tauphase(:,1:length(filter));
                [fig2,Lifetimephasefigmat(1+248*(k-1):248*k,1:248)]=lifetimefigs(subx,suby,fracMol_dIn,binIm_dd,mask_P_dd,dataMatrix_dd,CLIM_d,dataname,1,k,type(2),savelocation);
                close all;
                
                app.CommandsTextArea_2.Value='Processing... ';%fprintf('Processing... %d percent complete.',floor(count*100/(n)));
            end
            
            close all;
            %             app.CommandsTextArea_2.Value='Thank you for your patience. Processing is completed.';
            
            
            n=double(app.NoofImagesEditField.Value);
            %Plot all data in a matrix
            G_ddsum1 = matsum(G_ddsum,n);
            S_ddsum1 = matsum(S_ddsum,n);
            
            G_ddsumlogi=logical(G_ddsum);
            S_ddsumlogi=logical(S_ddsum);
            
            G_ddnnz=matsum(G_ddsumlogi,n);
            S_ddnnz=matsum(S_ddsumlogi,n);
            
            G_ddsum2=G_ddsum1./G_ddnnz;G_ddsum2(G_ddsum2==0)=nan;
            S_ddsum2=S_ddsum1./S_ddnnz;S_ddsum2(S_ddsum2==0)=nan;
            
            %% MAKING PHASOR PLOTS
            bcirc=linspace(0,1,1000);
            acirc=sqrt(0.25-(bcirc-0.5).^2);
            
            ax = app.PhasorPlot;
            cla(ax);
            p=plot(ax,bcirc,acirc,'k','LineWidth',2);hold(ax,'on');
            
            %     set(gca,'Fontsize',15);
            xlabel(ax,'G');
            ylabel(ax,'S')
            %Plotting monoexponential lifetimes on semicircle
            tau_unisem=0:1e-9:6e-9;tau_unisem1=tau_unisem*1e9;
            g_unisem=1./(1+w^2*tau_unisem.^2);
            s_unisem=w*tau_unisem./(1+w^2*tau_unisem.^2);
            p1=plot(ax,g_unisem,s_unisem,'ro','MarkerSize',8);
            
            % To add data labels to points on the graph
            for q=1:length(tau_unisem)
                text(ax,g_unisem(1,q)+0.025,s_unisem(1,q),sprintf('%g %s',tau_unisem1(q),"ns"));
            end
            
            % Plotting original phasor
            G_ddsum3=G_ddsum2(1,:);
            G_ddsum3 = G_ddsum3(~isnan(G_ddsum3));
            
            S_ddsum3=S_ddsum2(1,:);
            S_ddsum3 = S_ddsum3(~isnan(S_ddsum3));
            
            %Scatter Plotting
            X=G_ddsum3';Y=S_ddsum3';
            minx = min(X);
            maxx = max(X);
            miny = min(Y);
            maxy = max(Y);
            lambda = [];
            nbins = [];
            msize = 5;
            marker = 's';
            if isempty(nbins)
                nbins = [min(numel(unique(X)),200) ,min(numel(unique(Y)),200) ];
            end
            if isempty(lambda)
                lambda = 40;
            end
            edges1 = linspace(minx, maxx, nbins(1)+1);
            ctrs1 = edges1(1:end-1) + .5*diff(edges1);
            edges1 = [-Inf edges1(2:end-1) Inf];
            edges2 = linspace(miny, maxy, nbins(2)+1);
            ctrs2 = edges2(1:end-1) + .5*diff(edges2);
            edges2 = [-Inf edges2(2:end-1) Inf];
            [n,p] = size(X);
            bin = zeros(n,2);
            % Reverse the columns to put the first column of X along the horizontal
            % axis, the second along the vertical.
            [dum,bin(:,2)] = histc(X,edges1);
            [dum,bin(:,1)] = histc(Y,edges2);
            
            nbins([2 1]);
            H = accumarray(bin,1,nbins([2 1])) ./ n;
            G = smooth1D(H,nbins(2)/lambda);
            F = smooth1D(G',nbins(1)/lambda)';
            F = F./max(F(:));
            ind = sub2ind(size(F),bin(:,1),bin(:,2));
            col = F(ind);
            p3 = scatter(ax,X,Y,msize,col,marker);
            
            meanG= mean(G_ddsum3(1,:));
            meanS= mean(S_ddsum3(1,:));
            stdG= std(G_ddsum3(1,:));
            stdS= std(S_ddsum3(1,:));
            p4=errorbar(ax,meanG,meanS,stdS,stdS,stdG,stdG,'o','LineWidth',1.5,'color',rand(1,3));
            %          set(gca,'xlim',phasor_xlim,'ylim',phasor_ylim,'xtick',phasor_xtick,'ytick',phasor_ytick)
            
            
            legend(ax,'Universal semicircle','Mono Exp Lifetimes','Phasor Plot','Mean and SD','Location','southeast');
            %set(gca,'xlim',phasor_xlim,'ylim',phasor_ylim,'xtick',phasor_xtick,'ytick',phasor_ytick);
            %     ylim(ax,phasor_ylim);xlim(ax,phasor_xlim);
            title(ax,'Original phasor plot');
            figname=sprintf('%s_Phasorplot_OriginalMultiAcq.tif',dataname);
            figname=fullfile(savelocation,figname);
            %                     saveas(ax,figname);
            hold(ax,'off');
            
            %% Adding colour bar
            [cbar,cbarname]=colourbar_saveloc(CLIM_low,CLIM_high,savelocation);
            colourbar1=imread(cbarname);
            cla(app.Cbar);
            hold(app.Cbar,'on');
            imshow(colourbar1,'Parent',app.Cbar);
            hold(app.Cbar,'off');
%             close all;
            
            %%Adding cell images to main window
            
            
            %Iphase=imread(figphasename);
            figa=sprintf('%s_Phase_%d.tif',name,1);
            figa=fullfile(savelocation,figa);
            %subplot(1,n,k);
            hold(app.PhaseLifetime,'on');
            cla(app.PhaseLifetime);
            imshow(figa,'Parent',app.PhaseLifetime);
            title(app.PhaseLifetime,'Phase Lifetime');
            hold(app.PhaseLifetime,'off');
            
            
            %Iphase=imread(figphasename);
            figa=sprintf('%s_Mod_%d.tif',name,1);
            figa=fullfile(savelocation,figa);
            %subplot(1,n,k);
            hold(app.ModulationLifetime,'on');
            cla(app.ModulationLifetime);
            imshow(figa,'Parent',app.ModulationLifetime);
            title(app.ModulationLifetime,'Modulation Lifetime');
            hold(app.ModulationLifetime,'off');
            
            
            %% Calculating lifetime for tables
            
            %Phase lifetime check
            tauphase=w^-1.*(S_ddsum./G_ddsum);
            tauphase(tauphase==0)=nan;
            meantauphase=nanmean(tauphase,2);
            stdtauphase=nanstd(tauphase,0,2);
            
            %Mod lifetime check
            phi=atan(S_ddsum./G_ddsum);
            M1=G_ddsum./cos(phi);
            M2=S_ddsum./sin(phi);
            
            %for checking: should be the same as M1
            taumod=sqrt(1./M1.^2 -1)./w;
            taumod(taumod==0)=nan;
            meantaumod=nanmean(taumod,2);
            stdtaumod=nanstd(taumod,0,2);
            %             app.CommandsTextArea_2.Value=string(meantaumod);
            
            meantaumod(isnan(meantaumod)) = [];
            meantauphase(isnan(meantauphase)) = [];
            %Dropdown box
            app.CellImageDropDown.Items=Rownames;     %%% Drop down box for multiacq
            app.CellImageDropDown.ItemsData=1:n;
            
            
            %Adding values to the table
            fig=app.UITable;
            uit=uitable(fig);%'Data',tdata);
            uit.Data=[Rownames,meantaumod*1e9,meantauphase*1e9];
            
            
            %%% Multiacq: Phasor plot only
            if (app.PhasorplotsonlyButton_2.Value)
                
                close all;
                
                % figure(1);hold on; %hold(ModLifetimeFig,'on');
                figure('Name','Modulated Lifetime Images');hold on;
                n=double(app.NoofImagesEditField.Value);
                for k = 1:n
                    figa=sprintf('%s_Mod_%d.tif',name,k);
                    figa=fullfile(savelocation,figa);
                    subplot(1,n,k);
                    imshow(figa);
                    title(Rownames(k),'FontSize',6);
                end
                
                x0=10;
                y0=10;
                if n<5
                    width=256*n;
                else
                    width=256*4;
                end
                height=256;
                set(gcf,'position',[x0,y0,width,height]);
                hold off;


                figure('Name','Phase Lifetime Images');hold on;
                for k = 1:n
                    figa=sprintf('%s_Phase_%d.tif',name,k);
                    figa=fullfile(savelocation,figa);
                    subplot(1,n,k);
                    imshow(figa);
                    title(Rownames(k),'FontSize',6);
                    
                end
                
                set(gcf,'position',[x0,y0,width,height]);
                hold off;
                
                app.CommandsTextArea_2.Value='Thank you. The phasor plot analysis is complete.  ';%fprintf('P
                
            end %end for phasorplots only (multiacq)
            
            
            %%% Multiacquisition: Phasor to Cell
            
            if (app.PhasortoCellButton_2.Value)
                
                %% Prompting user to choose ROI
                close all;
                numROI=app.NoofROIDropDown_2.Value;numROI=str2double(numROI);
                %app.CommandsTextArea.Value=string(numROI);
                ROIpts=app.PointsROIDropDown_2.Value;ROIpts=str2double(ROIpts);
                %app.CommandsTextArea.Value=string(ROIpts);
                
                g=zeros(1,ROIpts);
                s=zeros(1,ROIpts);
                gstore=zeros(numROI,ROIpts);
                sstore=zeros(numROI,ROIpts);
                
                
                tauphase2mean=zeros(numROI,n);
                taumod2mean=zeros(numROI,n);
                %                 G_ddsum=zeros(numROI,maxfilterlength);
                %                 S_ddsum=zeros(numROI,maxfilterlength);
                %                 close all;
                
                
                %                 close all;
                % Creating phasor plot to mark ROI
                figure('Name','Phasor Plot','Visible','on'); hold on;
                
                p=plot(bcirc,acirc,'k','LineWidth',2);
                %                 hold(ax,'on');
                xlabel('G');
                ylabel('S')
                %Plotting monoexponential lifetimes on semicircle
                p1=plot(g_unisem,s_unisem,'ro','MarkerSize',8);
                
                % To add data labels to points on the graph
                for q=1:length(tau_unisem)
                    text(g_unisem(1,q)+0.025,s_unisem(1,q),sprintf('%g %s',tau_unisem1(q),"ns"));
                end
                
                p3 = scatter(X,Y,msize,col,marker);
                meanG= mean(G_ddsum3(1,:));
                meanS= mean(S_ddsum3(1,:));
                stdG= std(G_ddsum3(1,:));
                stdS= std(S_ddsum3(1,:));
                p4=errorbar(meanG,meanS,stdS,stdS,stdG,stdG,'o','LineWidth',1.5,'color',rand(1,3));
                
                legend('Universal semicircle','Mono Exp Lifetimes','Phasor Plot','Mean and SD','Location','southeast');
                title('Please select your ROI');
                n=app.NoofImagesEditField.Value;
                %                 app.CommandsTextArea.Value=['You have defined ' string(ROIpts) ' points for ' string(numROI) ' ROI. Please define the ROI on the phasor plot.'];
                %                 numROI=double(app.NoofROIDropDown_2.Value);
                %                 ROIpts=double(app.PointsROIDropDown_2.Value);
                
                for jj=1:numROI
                    [g,s]=ginput(ROIpts);
                    %                     if jj==1 && numROI==2
                    %                         app.CommandsTextArea.Value='Thank you. Please define the first ROI';
                    %                         title('Please select ROI_1');
                    
                    
                    gstore(jj,:)=g;
                    sstore(jj,:)=s;
                    hold on;
                    
                    if numROI==1
                        facecolour=[0 0 1];
                    else
                        facecolour=[[1 0 1]; %lavender
                            0 1 0]; %%green
                    end
                    p(4)=plot(polyshape(gstore(jj,:),sstore(jj,:)),'FaceColor',facecolour(jj,:));
                    legend(p(4),'ROI_1');
                    
                    if jj==2 && numROI==2
                        app.CommandsTextArea_2.Value='Thank you. Please define the second ROI';
                        title('Please selected ROI_2');
                    end
                    
                    %                     hold off;
                    
                    count=1;
                    while count<n+1
                        %                             for q=1:datanum
                        for h=1:n
                            [in,on]=inpolygon(G_ddsum(count,:),S_ddsum(count,:),g,s);
                            
                            if in==0
                                xpos=0;
                            else
                                %Lifetime calculation
                                G_ddpoly=G_ddsum(count,:);S_ddpoly=S_ddsum(count,:);
                                G_ddnew=G_ddpoly(in);S_ddnew=S_ddpoly(in);
                                
                                %Phase lifetime check
                                tauphase2=w^-1.*(S_ddnew./G_ddnew);
                                tauphase2mean(jj,h)=nanmean(tauphase2,2);
                                
                                %Mod lifetime check
                                phi=atan(S_ddnew./G_ddnew);
                                M1=G_ddnew./cos(phi);
                                %M2=S_ddsum(count,:)./sin(phi);
                                
                                %for checking: should be the same as M1
                                taumod2=sqrt(1./M1.^2 -1)./w;
                                taumod2 = round(taumod2,12);
                                
                                taumod2mean(jj,h)=nanmean(taumod2,2);
                                
                                Lifetimematmod=Lifetimemodfigmat(1+248*(count-1):248*count,1:248);
                                Lifetimematmod=round(Lifetimematmod,12);
                                
                                %search position on the cell image
                                xpos=zeros(100,length(taumod2));
                                ypos=zeros(100,length(taumod2));
                                for k=1:length(taumod2)
                                    [findx,findy]=find(Lifetimematmod==taumod2(k));
                                    lfind=length(findx);
                                    for kk=1:lfind
                                        
                                        xpos(kk,k) = findx(kk);
                                        ypos(kk,k) = findy(kk);
                                    end
                                end
                                
                                %Modulation lifetime
                                Lifetimematmod1=Lifetimematmod;
                            end
                            colourval=[1,1];
                            if xpos== 0
                                Lifetimematmod1=zeros(size(Lifetimemodfigmat(1+248*(count-1):248*count,1:248)));
                            else
                                for k=1:length(xpos(:,1)) %rows in xpos
                                    for kk=1:length(xpos(1,:)) %column in xpos
                                        if xpos(k,kk)>0
                                            xpos1=xpos(k,kk);ypos1=ypos(k,kk);
                                            Lifetimematmod1(xpos1,ypos1)=colourval(jj);
                                        elseif xpos(k,kk)==0
                                            Lifetimematmod1;
                                        else
                                            Lifetimematmod1(xpos1,ypos1)=nan;
                                            
                                        end
                                    end
                                end
                            end
                            
                            
                            Lifetimematmod1(Lifetimematmod1<1)=nan;
                            colour=[10,64,30]; %64:red 30:green 10:blue
                            
                            
                            imagename=sprintf('%s_%d.sdt',name,h);
                            
                            %Importing TCSPC image
                            ddSdt=fullfile(string(app.CellImageFolderEditField.Value),imagename);
                            dataMatrix_dd= loadBHfileusingmeasDescBlock(ddSdt);
                            dataMatrix_dd = permute(dataMatrix_dd,[3,2,1]);
                            dataMatrix_dd = double(dataMatrix_dd);
                            intenMatrix_dd = sum(dataMatrix_dd,3);
                            
                            
                            if (app.MasktifButton_2.Value)
                                %Importing tif masks
                                maskname=sprintf('%s_%d segmentation.tif',name,h);
                                maskfile=fullfile(string(app.CellMasktifEditField_2.Value),maskname);
                                
                                raw_data1=imread(maskfile);
                                raw_data2=single(raw_data1);
                                %unhash for checking purposes
                                %imagesc(raw_data2);
                                %title('Imported .tif mask (yellows - 1, purple - 0');
                                
                                raw_data=repmat(raw_data2,[1,1,256]);
                                mask_P_dd=logical(raw_data);
                                
                            end
                            
                            if (app.IntensityButton_2.Value)
                                pmin_dd=app.OrmaskbyintensityEditField_2.Value;
                                pmax_dd=app.tointensityEditField_3.Value;
                                mask_P_dd = (intenMatrix_dd>pmin_dd)&(intenMatrix_dd<pmax_dd);
                                mask_P_dd = repmat(mask_P_dd,[1,1,256]);
                            end
                            
                            sub_Mat=submatstore(1+248*(count-1):248*count,1:248);
                            [fig]=lifetimefigs_phasor_multiacq(binIm_dd,mask_P_dd,dataMatrix_dd,[0,1],dataname,1,type(1),Lifetimematmod1,colour(jj),h,jj,sub_Mat,savelocation);
                            clear mask_P_dd dataMatrix_dd Lifetimematmod1
                            %                             close all;
                            %                             clc;
                            count=count+1;
                        end
                    end
                    %                     clc;
                end
                title('Thank you for making your selection.');
                legend('Universal semicircle','Mono Exp Lifetimes','Phasor Plot','Mean and SD','ROI_1','ROI_2','Location','southeast');
                hold off;
                app.CommandsTextArea_2.Value='Thank you for making your selection for this phasor to cell image analysis. Please wait for resulting images.';
                close all;
                
                % Figure 1 Modulation Lifetime Images
                figure('Name','Modulated Lifetime Images');hold on;
                n=double(app.NoofImagesEditField.Value);
                for k = 1:n
                    figa=sprintf('%s_Mod_%d.tif',name,k);
                    figa=fullfile(savelocation,figa);
                    subplot(1,n,k);
                    imshow(figa);
                    title(Rownames(k),'FontSize',6);
                end
                
                x0=10;
                y0=10;
                if n<5
                    width=256*n;
                else
                    width=256*4;
                end
                height=256;
                set(gcf,'position',[x0,y0,width,height]);
                hold off;
                
                
                % Figure 2: Phase lifetime images
                figure('Name','Phase Lifetime Images');hold on;
                for k = 1:n
                    figa=sprintf('%s_Phase_%d.tif',name,k);
                    figa=fullfile(savelocation,figa);
                    subplot(1,n,k);
                    imshow(figa);
                    title(Rownames(k),'FontSize',6);
                    
                end
                
                set(gcf,'position',[x0,y0,width,height]);
                hold off;
                
                % Figure 3: Phasor to Cell images
                figure('Name','Phasor to Cell Images');hold on;
                count=1;
                while count<n+1
                    for h=1:n
                        figA=sprintf('%s_%s_%d_PHASORROI_%d.tif',name,type(1),h,1);
                        figA=fullfile(savelocation,figA);
                        figB=sprintf('%s_%s_%d_PHASORROI_%d.tif',name,type(1),h,2);
                        figB=fullfile(savelocation,figB);
                        if numROI==2
                            subplot(1,n,h);
                            figname=sprintf('%s_%s_%d_PHASORROI_merged.tif',name,type(1),h);
                            figname=fullfile(savelocation,figname);
                            imwrite(imfuse(imread(figA),imread(figB),'ColorChannels',[2 1 2]),figname);
                            imshow(imfuse(imread(figA),imread(figB),'ColorChannels',[2 1 2]));
                        else
                            subplot(1,n,h);
                            imshow(imread(figA));
                        end
                        title(Rownames(count,1),'FontSize',6);
                        count=count+1;
                    end
                end
                
                set(gcf,'position',[x0,y0,width,height]);
                hold off;
                
                
                %Updating figure on main window
                ax=app.PhaseLifetime;
                hold(ax,'on');
%                 nameimg=app.Property;
                %                 count=1;
%                 CellImageDropDownValueChanged(app, event);

                %                 for h=1:n
                h=1; %Plots first figure by default
                figA=sprintf('%s_%s_%d_PHASORROI_%d.tif',name,type(1),h,1);
                figA=fullfile(savelocation,figA);
                figB=sprintf('%s_%s_%d_PHASORROI_%d.tif',name,type(1),h,2);
                figB=fullfile(savelocation,figB);
                if numROI==2
                    %                             subplot(1,n,h);
                    figname=sprintf('%s_%s_%d_PHASORROI_merged.tif',name,type(1),h);
                    figname=fullfile(savelocation,figname);
                    imwrite(imfuse(imread(figA),imread(figB),'ColorChannels',[2 1 2]),figname);
                    imshow(imfuse(imread(figA),imread(figB),'ColorChannels',[2 1 2]),'Parent',ax);
                else
                    subplot(1,n,h);
                    imshow(imread(figA),'Parent',ax);
                end
                title(ax,'Phasor to Cell');
                %                 end
                
                hold(ax,'off');
                
                
                %Updating phasor plot
                ax = app.PhasorPlot;
                hold(ax,'on');
                for jj=1:numROI
                    if numROI==1
                        facecolour=[0 0 1];
                    else
                        facecolour=[[1 0 1]; %lavender
                            0 1 0]; %%green
                    end
                    p(jj+3)=plot(ax,polyshape(gstore(jj,:),sstore(jj,:)),'FaceColor',facecolour(jj,:));
                end
                
                if numROI==1
                    legend(ax,p(4),'ROI_1');
                else
                    legend(ax,[p(4),p(5)],'ROI_1','ROI_2');
                end
                figname=sprintf('%s_Phasorplot_Phasor2Cell_MultiAcq.tif',dataname);drawnow;
                figname=fullfile(savelocation,figname);
                %                 saveas(ax,figname);
                hold(ax,'off');
                
            end
        end

        % Callback function
        function BrowseButton_21Pushed(app, event)
            %Cell Image folder for Dataset 4
            selpath = uigetdir;
            [~,name] = fileparts(selpath);
            app.CellImageFolderEditField_4.Value=selpath;
            app.ImageNameEditField_4.Value=name;
            
        end

        % Callback function
        function BrowseButton_22Pushed(app, event)
            %tif masks for Dataset 4
            selpath = uigetdir;
            [~,name] = fileparts(selpath);
            app.CellMasktifEditField_9.Value=selpath;
        end

        % Callback function
        function BrowseButton_19Pushed(app, event)
            %Cell Image folder for Dataset 3
            selpath = uigetdir;
            [~,name] = fileparts(selpath);
            app.CellImageFolderEditField_3.Value=selpath;
            app.ImageNameEditField_3.Value=name;
        end

        % Callback function
        function BrowseButton_20Pushed(app, event)
            %tif masks for Dataset 3
            selpath = uigetdir;
            [~,name] = fileparts(selpath);
            app.CellMasktifEditField_8.Value=selpath;
        end

        % Callback function
        function BrowseButton_17Pushed(app, event)
            %Cell Image folder for Dataset 2
            selpath = uigetdir;
            [~,name] = fileparts(selpath);
            app.CellImageFolderEditField_2.Value=selpath;
            app.ImageNameEditField_2.Value=name;
        end

        % Callback function
        function BrowseButton_18Pushed(app, event)
            %tif masks for Dataset 2
            selpath = uigetdir;
            [~,name] = fileparts(selpath);
            app.CellMasktifEditField_7.Value=selpath;
        end

        % Button pushed function: BrowseButton_7
        function BrowseButton_7Pushed(app, event)
            %Cell Image folder for Dataset %1
            selpath = uigetdir;
            [~,name] = fileparts(selpath);
            app.CellImageFolderEditField.Value=selpath;
            app.ImageNameEditField.Value=name;
        end

        % Button pushed function: BrowseButton_8
        function BrowseButton_8Pushed(app, event)
            %tif masks for Dataset 1
            selpath = uigetdir;
            [~,name] = fileparts(selpath);
            app.CellMasktifEditField_2.Value=selpath;
        end

        % Callback function
        function BrowseButton_15Pushed(app, event)
            [filenameTCSPC, pathname]=uigetfile({'*.sdt'},'Select TCSPC cell image file (.sdt)');
            fullpathname_TCSPC=strcat(pathname,filenameTCSPC);
            %test=fileread(fullpathname_TCSPC);
            app.CellImagesdtEditField_2.Value=fullpathname_TCSPC;
            app.EditField_6.Value=strcat(filenameTCSPC);
        end

        % Callback function
        function BrowseButton_16Pushed(app, event)
            [filenameMASK, pathname]=uigetfile({'*.tif'},'Select cell mask file (.tif)');
            fullpathname_mask=strcat(pathname,filenameMASK);
            %test=fileread(fullpathname_TCSPC);
            app.NoofImagesEditField_5.Value=fullpathname_mask;
        end

        % Callback function
        function BrowseButton_24Pushed(app, event)
            selpath = uigetdir;
            %[~,name] = fileparts(selpath);
            app.SaveImagesinEditField_2.Value=selpath;
            %app.ImageNameEditField_4.Value=name;
        end

        % Callback function
        function NoofImagesButton_2Pushed(app, event)
            a2 = dir(fullfile(app.CellImageFolderEditField_2.Value,'*.sdt'));
            n2 = numel(a2);
            app.NoofImagesEditField_9.Value=n2;
        end

        % Callback function
        function NoofImagesButtonPushed(app, event)
            a1 = dir(fullfile(app.CellImageFolderEditField.Value,'*.sdt'));
            n1 = numel(a1);
            app.NoofImagesEditField_5.Value=n1;
        end

        % Callback function
        function NoofImagesButton_3Pushed(app, event)
            a3 = dir(fullfile(app.CellImageFolderEditField_3.Value,'*.sdt'));
            n3 = numel(a3);
            app.NoofImagesEditField_10.Value=n3;
        end

        % Callback function
        function NoofImagesButton_4Pushed(app, event)
            a4 = dir(fullfile(app.CellImageFolderEditField_4.Value,'*.sdt'));
            n4 = numel(a4);
            app.NoofImagesEditField_11.Value=n4;
        end

        % Button pushed function: BrowseButton_6
        function BrowseButton_6Pushed(app, event)
            [filenameIRF, pathname]=uigetfile({'*.sdt'},'Select IRF file (.sdt)');
            fullpathname_IRF=strcat(pathname,filenameIRF);
            %test=fileread(fullpathname_TCSPC);
            app.IRFImagesdtEditField_2.Value=fullpathname_IRF;
        end

        % Button pushed function: NoofImagesButton
        function NoofImagesButtonPushed2(app, event)
            a2 = dir(fullfile(app.CellImageFolderEditField.Value,'*.sdt'));
            n2 = numel(a2);
            app.NoofImagesEditField.Value=n2;
        end

        % Value changed function: CellImageDropDown
        function CellImageDropDownValueChanged(app, event)
                app.Property=app.CellImageDropDown.Value; %position value
                dataname=string(app.ImageNameEditField.Value);
                type=["Mod","Phase"];
                h=double(app.Property);
                savelocation=string(app.SaveinEditField_MAcq.Value);
                
%                 nameimg=app.CellImageDropDown.Items{app.Property};
%                 app.CommandsTextArea_2.Value=string(nameimg);
                
%                 dataname=string(app.ImageNameEditField.Value);
                if (app.PhasortoCellButton_2.Value)
                %Updating figure on main window
                %Phasor 2 Cell Image
                numROI=str2double(app.NoofROIDropDown_2.Value);
                
                ax=app.PhaseLifetime;
                hold(ax,'on');
                figA=sprintf('%s_%s_%d_PHASORROI_%d.tif',dataname,type(1),h,1);
                figA=fullfile(savelocation,figA);                
                if numROI==2
                    figname=sprintf('%s_%s_%d_PHASORROI_merged.tif',dataname,type(1),h);
                    figname=fullfile(savelocation,figname);
                    imshow(imread(figname),'Parent',ax);
                else                    
                    imshow(imread(figA),'Parent',ax);
                end
                title(ax,'Phasor to Cell');
                hold(ax,'off');
                
                %Modulation Lifetime Image
                
                % Abeta42_Mod_1.tif
                
                figmodname=sprintf('%s_%s_%d.tif',dataname,type(1),h);
                figmodname=fullfile(savelocation,figmodname);
                
                hold(app.ModulationLifetime,'on');
                cla(app.ModulationLifetime);
                Imod=imread(figmodname);
                imshow(Imod,'Parent',app.ModulationLifetime);
                hold(app.ModulationLifetime,'off');


                end
                
                if (app.PhasorplotsonlyButton_2.Value)
                              
                % Updating modulation lifetime image    
                figmodname=sprintf('%s_%s_%d.tif',dataname,type(1),h);
                figmodname=fullfile(savelocation,figmodname);
                
                hold(app.ModulationLifetime,'on');
                cla(app.ModulationLifetime);
                Imod=imread(figmodname);
                imshow(Imod,'Parent',app.ModulationLifetime);
                hold(app.ModulationLifetime,'off');
                
                % Updating phase lifetime image
                figphasename=sprintf('%s_%s_%d.tif',dataname,type(2),h);
                figphasename=fullfile(savelocation,figphasename);
                
                hold(app.PhaseLifetime,'on');
                cla(app.PhaseLifetime);
                Iphase=imread(figphasename);
                imshow(Iphase,'Parent',app.PhaseLifetime);
                hold(app.PhaseLifetime,'off');
                
                end

        end

        % Value changed function: SameasSingleImageCheckBox
        function SameasSingleImageCheckBoxValueChanged(app, event)
            value = app.SameasSingleImageCheckBox.Value;
            if value==1
                app.RepetitionRateEditField_2.Value=app.RepetitionRateEditField.Value;
            end
            
        end

        % Value changed function: SameasMultiacquisitionCheckBox
        function SameasMultiacquisitionCheckBoxValueChanged(app, event)
            value = app.SameasMultiacquisitionCheckBox.Value;
            if value==1
                app.RepetitionRateEditField.Value=app.RepetitionRateEditField_2.Value;
            end
        end

        % Value changed function: SameasSingleImageCheckBox_2
        function SameasSingleImageCheckBox_2ValueChanged(app, event)
            value = app.SameasSingleImageCheckBox_2.Value;
            if value==1
                app.KnownLifetimeEditField_2.Value=app.KnownLifetimeEditField.Value;
                app.IRFImagesdtEditField_2.Value=app.IRFImagesdtEditField.Value;
            end
        end

        % Value changed function: SameasMultiacquisitionCheckBox_2
        function SameasMultiacquisitionCheckBox_2ValueChanged(app, event)
            value = app.SameasMultiacquisitionCheckBox_2.Value;
            if value==1
                app.KnownLifetimeEditField.Value=app.KnownLifetimeEditField_2.Value;
                app.IRFImagesdtEditField.Value=app.IRFImagesdtEditField_2.Value;
            end
        end

        % Value changed function: SameasSingleImageCheckBox_3
        function SameasSingleImageCheckBox_3ValueChanged(app, event)
            value = app.SameasSingleImageCheckBox_3.Value;
            if value==1
                app.LifetimeRangeEditField_2.Value=app.LifetimeRangeEditField.Value;
                app.toEditField_3.Value=app.toEditField.Value;
                app.OrmaskbyintensityEditField_2.Value=app.OrmaskbyintensityEditField.Value;
                app.tointensityEditField_3.Value=app.tointensityEditField_2.Value;
            end
        end

        % Value changed function: SameasMultiacquisitionCheckBox_3
        function SameasMultiacquisitionCheckBox_3ValueChanged(app, event)
            value = app.SameasMultiacquisitionCheckBox_3.Value;
            if value==1
                app.LifetimeRangeEditField.Value=app.LifetimeRangeEditField_2.Value;
                app.toEditField.Value=app.toEditField_3.Value;
                app.OrmaskbyintensityEditField.Value=app.OrmaskbyintensityEditField_2.Value;
                app.tointensityEditField_2.Value=app.tointensityEditField_3.Value;
            end
            
        end

        % Callback function
        function ImageClicked(app, event)
            
        end

        % Size changed function: IntroductionTab
        function IntroductionTabSizeChanged(app, event)
            
        end

        % Button pushed function: BrowseButton_9
        function BrowseButton_9Pushed(app, event)
            [filenameTCSPC, pathname]=uigetfile({'*.sdt'},'Select TCSPC cell image file for masking (.sdt)');
            fullpathname_TCSPC=strcat(pathname,filenameTCSPC);
            %test=fileread(fullpathname_TCSPC);
            app.CellImagesdtEditField_2.Value=fullpathname_TCSPC;
            app.FileNamesdtEditField.Value=strcat(filenameTCSPC(1:end-4));
        end

        % Button pushed function: BrowseButton_10
        function BrowseButton_10Pushed(app, event)
            %Save directory for masks
            selpath = uigetdir;
            [~,name] = fileparts(selpath);
            app.SaveinEditField.Value=selpath;
            %             app.ImageNameEditField.Value=name;
        end

        % Value changed function: StartButton_3
        function StartButton_3ValueChanged(app, event)
            %%%% Cell Segmentation
            
            ddRefSdt=string(app.CellImagesdtEditField_2.Value);
            filenamestring=string(app.FileNamesdtEditField.Value);
            app.CommandsTextArea_CellSeg.Value=['Importing' filenamestring ' for masking'];
            ROIpts=str2double(app.PointsMaskDropDown.Value);
            % Import data.
            % Note that the function "loadBHfileusingmeasDescBlock" for
            % importing Becker & Hickl FLIM data is written by Dr. Sean Warren.
            dataMatrix_dd = loadBHfileusingmeasDescBlock(ddRefSdt);
            dataMatrix_dd = permute(dataMatrix_dd,[3,2,1]);
            dataMatrix_dd = double(dataMatrix_dd);
            savedir=string(app.SaveinEditField.Value);
            % Creating intensity image for masking
            [fig_cellseg, figname_cellseg] = lifetimefigs_intenonly(dataMatrix_dd,filenamestring,savedir);
            
            %             imshow(I,'Parent',app.ModulationLifetime);%set(h,'YTick',yTickNumbers,'YTickLabel',labels);
            close all;
            figure('Name','Cell segmentation');hold on;
            imshow(figname_cellseg);
            title('Please define a mask.');
            [xstore,ystore]=ginput(ROIpts);
%             %disp('Thank you. Please wait.');
%             app.CommandsTextArea_CellSeg.Value=num2str(xstore);
%             app.CommandsTextArea_CellSeg.Value=num2str(ystore);
%             nColumns = size(x,2);
%             x=xstore(1,:);
%                     y=ystore(1,:);
x=xstore';y=ystore';
nColumns = size(x,2);
AB = [x,y]';
AB = reshape(AB(:),nColumns,[])';
for l=1:nColumns
    AB_New(1,2*l-1:2*l)=AB(:,l);
end
          
            I=imread(figname_cellseg);
            I = insertShape(I,'FilledPolygon',AB_New(1,:),'Color', 'magenta','Opacity',0.5);
            imshow(I);
            title('Thank you for defining your mask.')
            hold off;
            
            %Creating a logical mask
            raw_data1=zeros(256,256);
            raw_data1(1:248,1:248) = roipoly(I,xstore,ystore);
            raw_data1=logical(raw_data1);
            
            figname1=sprintf('%s segmentation.tif',filenamestring);
            figname=fullfile(savedir,figname1);
            imwrite(raw_data1,figname);
            
            app.CommandsTextArea_CellSeg.Value=['Saving as ' figname1 ' to ' savedir];
            
        end

        % Button pushed function: BrowseButton_11
        function BrowseButton_11Pushed(app, event)
            %Save directory for masks
            selpath = uigetdir;
            [~,name] = fileparts(selpath);
            app.SaveinEditField_2.Value=selpath;
        end

        % Button pushed function: BrowseButton_12
        function BrowseButton_12Pushed(app, event)
            %Save directory for masks
            selpath = uigetdir;
            [~,name] = fileparts(selpath);
            app.SaveinEditField_MAcq.Value=selpath;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {734, 734, 734};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.ImagesOri.Layout.Row = 2;
                app.ImagesOri.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {734, 734};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.ImagesOri.Layout.Row = 2;
                app.ImagesOri.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x', 529};
                app.ImagesOri.Layout.Row = 1;
                app.ImagesOri.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImagesOri = uipanel(app.GridLayout);
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.CenterPanel);
            app.IntroductionTab = uitab(app.TabGroup3);
            app.IntroductionTab.SizeChangedFcn = createCallbackFcn(app, @IntroductionTabSizeChanged, true);
            app.IntroductionTab.Title = 'Introduction';
            app.AGlobalApproachtoTCSPCDataAnalysisLabel = uilabel(app.IntroductionTab);
            app.AGlobalApproachtoTCSPCDataAnalysisLabel.Text = 'A Global Approach to TCSPC Data Analysis ';
            app.ThisapplicationallowsforLabel = uilabel(app.IntroductionTab);
            app.ThisapplicationallowsforLabel.Text = 'This application allows for:';
            app.PhasoranalysisGenerateslifetimeimagesandtabulatedvaluesLabel = uilabel(app.IntroductionTab);
            app.PhasoranalysisGenerateslifetimeimagesandtabulatedvaluesLabel.Text = ' -- Phasor analysis: Generates lifetime images and tabulated values';
            app.MultifileprocessingbyphasoranalysisLabel_2 = uilabel(app.IntroductionTab);
            app.MultifileprocessingbyphasoranalysisLabel_2.Text = ' -- Cell to Phasor analysis*: Mapping of selected ROI on the cell image ';
            app.tocorrespondingphasorsinthephasorplotLabel = uilabel(app.IntroductionTab);
            app.tocorrespondingphasorsinthephasorplotLabel.Text = ' to corresponding phasors in the phasor plot';
            app.MultifileprocessingbyphasoranalysisLabel_3 = uilabel(app.IntroductionTab);
            app.MultifileprocessingbyphasoranalysisLabel_3.Text = ' -- Phasor to Cell analysis: Mapping of selected ROI on the phasor plot  ';
            app.tocorrespondingpixelsinthecellimageLabel = uilabel(app.IntroductionTab);
            app.tocorrespondingpixelsinthecellimageLabel.Text = ' to corresponding pixels in the cell image';
            app.Pleasereportanyerrorsbugstocwc53camacukLabel = uilabel(app.IntroductionTab);
            app.Pleasereportanyerrorsbugstocwc53camacukLabel.Text = 'Please report any errors/bugs to cwc53@cam.ac.uk';
            app.usingPhasorPlotsLabel = uilabel(app.IntroductionTab);
            app.usingPhasorPlotsLabel.Text = 'using Phasor Plots';
            app.NotesLabel = uilabel(app.IntroductionTab);
            app.NotesLabel.Text = 'Notes:';
            app.RequiresallmscriptsincludedinthezippedfolderLabel = uilabel(app.IntroductionTab);
            app.RequiresallmscriptsincludedinthezippedfolderLabel.Text = ' -- Requires all m-scripts included in the zipped folder';
            app.RequirestheComputerVisionToolboxpleaseinstallbeforehandLabel = uilabel(app.IntroductionTab);
            app.RequirestheComputerVisionToolboxpleaseinstallbeforehandLabel.Text = ' -- Requires the Computer Vision Toolbox (please install beforehand)';
            app.Image = uiimage(app.IntroductionTab);
            app.Image.ImageSource = 'introcollagefinal.jpg';
            app.MultifileprocessingbyphasoranalysisLabel_4 = uilabel(app.IntroductionTab);
            app.MultifileprocessingbyphasoranalysisLabel_4.Text = ' -- Cell segmentation*: Creates .tif masks for .sdt files ';
            app.OperatesonBHsdtfilessavedinScanSyncInmodeLabel = uilabel(app.IntroductionTab);
            app.OperatesonBHsdtfilessavedinScanSyncInmodeLabel.Text = ' -- Operates on B&H .sdt files (saved in Scan Sync In mode)';
            app.SingleimageprocessingonlyLabel = uilabel(app.IntroductionTab);
            app.SingleimageprocessingonlyLabel.Text = '* Single image processing only';
            app.RequiresMATLAB2019bruntimeLabel = uilabel(app.IntroductionTab);
            app.RequiresMATLAB2019bruntimeLabel.Text = ' -- Requires MATLAB 2019b runtime';
            app.MultiacquisitionTab = uitab(app.TabGroup3);
            app.MultiacquisitionTab.Title = 'Multiacquisition';
            app.TabGroup_2 = uitabgroup(app.MultiacquisitionTab);
            app.LaserSettingsTab_2 = uitab(app.TabGroup_2);
            app.LaserSettingsTab_2.Title = 'Laser Settings';
            app.MHzLabel_2 = uilabel(app.LaserSettingsTab_2);
            app.MHzLabel_2.Text = '[MHz]';
            app.RepetitionRateEditField_2Label = uilabel(app.LaserSettingsTab_2);
            app.RepetitionRateEditField_2Label.Text = 'Repetition Rate';
            app.RepetitionRateEditField_2 = uieditfield(app.LaserSettingsTab_2, 'numeric');
            app.SameasSingleImageCheckBox = uicheckbox(app.LaserSettingsTab_2);
            app.SameasSingleImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @SameasSingleImageCheckBoxValueChanged, true);
            app.SameasSingleImageCheckBox.Text = 'Same as Single Image';
            app.CellImageTab_2 = uitab(app.TabGroup_2);
            app.CellImageTab_2.Title = 'Cell Image';
            app.nsLabel_5 = uilabel(app.CellImageTab_2);
            app.nsLabel_5.Text = '[ns]';
            app.LifetimeRangeEditField_2Label = uilabel(app.CellImageTab_2);
            app.LifetimeRangeEditField_2Label.Text = 'Lifetime Range';
            app.LifetimeRangeEditField_2 = uieditfield(app.CellImageTab_2, 'numeric');
            app.toEditField_2Label_2 = uilabel(app.CellImageTab_2);
            app.toEditField_2Label_2.Text = 'to';
            app.toEditField_3 = uieditfield(app.CellImageTab_2, 'numeric');
            app.BrowseButton_7 = uibutton(app.CellImageTab_2, 'push');
            app.BrowseButton_7.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_7Pushed, true);
            app.BrowseButton_7.Text = 'Browse';
            app.BrowseButton_8 = uibutton(app.CellImageTab_2, 'push');
            app.BrowseButton_8.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_8Pushed, true);
            app.BrowseButton_8.Text = 'Browse';
            app.CellMasktifEditField_2 = uieditfield(app.CellImageTab_2, 'text');
            app.photonspxLabel_2 = uilabel(app.CellImageTab_2);
            app.photonspxLabel_2.Text = 'photons/px';
            app.MaskingButtonGroup = uibuttongroup(app.CellImageTab_2);
            app.MaskingButtonGroup.Title = 'Masking';
            app.MasktifButton_2 = uiradiobutton(app.MaskingButtonGroup);
            app.MasktifButton_2.Text = 'Mask (.tif)';
            app.MasktifButton_2.Value = true;
            app.IntensityButton_2 = uiradiobutton(app.MaskingButtonGroup);
            app.IntensityButton_2.Text = 'Intensity';
            app.OrmaskbyintensityEditField_2 = uieditfield(app.CellImageTab_2, 'numeric');
            app.CellImageFolderEditFieldLabel = uilabel(app.CellImageTab_2);
            app.CellImageFolderEditFieldLabel.Text = 'Cell Image Folder';
            app.CellImageFolderEditField = uieditfield(app.CellImageTab_2, 'text');
            app.ImageNameEditFieldLabel = uilabel(app.CellImageTab_2);
            app.ImageNameEditFieldLabel.Text = 'Image Name';
            app.ImageNameEditField = uieditfield(app.CellImageTab_2, 'text');
            app.toEditField_2Label_3 = uilabel(app.CellImageTab_2);
            app.toEditField_2Label_3.Text = 'to';
            app.tointensityEditField_3 = uieditfield(app.CellImageTab_2, 'numeric');
            app.NoofImagesEditField = uieditfield(app.CellImageTab_2, 'numeric');
            app.NoofImagesButton = uibutton(app.CellImageTab_2, 'push');
            app.NoofImagesButton.ButtonPushedFcn = createCallbackFcn(app, @NoofImagesButtonPushed2, true);
            app.NoofImagesButton.Text = 'No. of Images';
            app.SameasSingleImageCheckBox_3 = uicheckbox(app.CellImageTab_2);
            app.SameasSingleImageCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @SameasSingleImageCheckBox_3ValueChanged, true);
            app.SameasSingleImageCheckBox_3.Text = 'Same as Single Image';
            app.IRFTab_2 = uitab(app.TabGroup_2);
            app.IRFTab_2.Title = 'IRF';
            app.BrowseButton_6 = uibutton(app.IRFTab_2, 'push');
            app.BrowseButton_6.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_6Pushed, true);
            app.BrowseButton_6.Text = 'Browse';
            app.nsLabel_4 = uilabel(app.IRFTab_2);
            app.nsLabel_4.Text = '[ns]';
            app.IRFImagesdtEditField_2Label = uilabel(app.IRFTab_2);
            app.IRFImagesdtEditField_2Label.Text = 'IRF Image (.sdt)';
            app.IRFImagesdtEditField_2 = uieditfield(app.IRFTab_2, 'text');
            app.KnownLifetimeEditField_2Label = uilabel(app.IRFTab_2);
            app.KnownLifetimeEditField_2Label.Text = 'Known Lifetime';
            app.KnownLifetimeEditField_2 = uieditfield(app.IRFTab_2, 'numeric');
            app.SameasSingleImageCheckBox_2 = uicheckbox(app.IRFTab_2);
            app.SameasSingleImageCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SameasSingleImageCheckBox_2ValueChanged, true);
            app.SameasSingleImageCheckBox_2.Text = 'Same as Single Image';
            app.StartButton_2 = uibutton(app.MultiacquisitionTab, 'state');
            app.StartButton_2.ValueChangedFcn = createCallbackFcn(app, @StartButton_2ValueChanged, true);
            app.StartButton_2.Text = 'Start';
            app.CommandsTextArea_2Label = uilabel(app.MultiacquisitionTab);
            app.CommandsTextArea_2Label.Text = 'Commands';
            app.CommandsTextArea_2 = uitextarea(app.MultiacquisitionTab);
            app.ProcessingModeButtonGroup_2 = uibuttongroup(app.MultiacquisitionTab);
            app.ProcessingModeButtonGroup_2.Title = 'Processing Mode';
            app.PhasorplotsonlyButton_2 = uiradiobutton(app.ProcessingModeButtonGroup_2);
            app.PhasorplotsonlyButton_2.Text = 'Phasor plots only';
            app.PhasorplotsonlyButton_2.Value = true;
            app.PhasortoCellButton_2 = uiradiobutton(app.ProcessingModeButtonGroup_2);
            app.PhasortoCellButton_2.Text = 'Phasor to Cell';
            app.NoofROIDropDown_2Label = uilabel(app.ProcessingModeButtonGroup_2);
            app.NoofROIDropDown_2Label.Text = 'No of ROI';
            app.NoofROIDropDown_2 = uidropdown(app.ProcessingModeButtonGroup_2);
            app.NoofROIDropDown_2.Items = {'1', '2'};
            app.NoofROIDropDown_2.Value = '1';
            app.PointsROIDropDown_2Label = uilabel(app.ProcessingModeButtonGroup_2);
            app.PointsROIDropDown_2Label.Text = 'Points/ROI';
            app.PointsROIDropDown_2 = uidropdown(app.ProcessingModeButtonGroup_2);
            app.PointsROIDropDown_2.Items = {'3', '4', '5', '6', '7', '8', '9', '10'};
            app.PointsROIDropDown_2.Value = '4';
            app.BrowseButton_12 = uibutton(app.MultiacquisitionTab, 'push');
            app.BrowseButton_12.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_12Pushed, true);
            app.BrowseButton_12.Text = 'Browse';
            app.SaveinEditField_2Label_2 = uilabel(app.MultiacquisitionTab);
            app.SaveinEditField_2Label_2.Text = 'Save in';
            app.SaveinEditField_MAcq = uieditfield(app.MultiacquisitionTab, 'text');
            app.SingleTab = uitab(app.TabGroup3);
            app.SingleTab.Title = 'Single';
            app.TabGroup = uitabgroup(app.SingleTab);
            app.LaserSettingsTab = uitab(app.TabGroup);
            app.LaserSettingsTab.Title = 'Laser Settings';
            app.MHzLabel = uilabel(app.LaserSettingsTab);
            app.MHzLabel.Text = '[MHz]';
            app.RepetitionRateEditFieldLabel = uilabel(app.LaserSettingsTab);
            app.RepetitionRateEditFieldLabel.Text = 'Repetition Rate';
            app.RepetitionRateEditField = uieditfield(app.LaserSettingsTab, 'numeric');
            app.SameasMultiacquisitionCheckBox = uicheckbox(app.LaserSettingsTab);
            app.SameasMultiacquisitionCheckBox.ValueChangedFcn = createCallbackFcn(app, @SameasMultiacquisitionCheckBoxValueChanged, true);
            app.SameasMultiacquisitionCheckBox.Text = 'Same as Multiacquisition';
            app.CellImageTab = uitab(app.TabGroup);
            app.CellImageTab.Title = 'Cell Image';
            app.BrowseButton = uibutton(app.CellImageTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.CellImageTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.nsLabel = uilabel(app.CellImageTab);
            app.nsLabel.Text = '[ns]';
            app.CellImagesdtEditFieldLabel = uilabel(app.CellImageTab);
            app.CellImagesdtEditFieldLabel.Text = 'Cell Image (.sdt)';
            app.CellImagesdtEditField = uieditfield(app.CellImageTab, 'text');
            app.CellImagesdtEditField.ValueChangedFcn = createCallbackFcn(app, @CellImagesdtEditFieldValueChanged, true);
            app.CellMasktifEditField = uieditfield(app.CellImageTab, 'text');
            app.photonspxLabel = uilabel(app.CellImageTab);
            app.photonspxLabel.Text = 'photons/px';
            app.FilenamesdtButtonGroup = uibuttongroup(app.CellImageTab);
            app.FilenamesdtButtonGroup.Title = 'File name (.sdt)';
            app.MasktifButton = uiradiobutton(app.FilenamesdtButtonGroup);
            app.MasktifButton.Text = 'Mask (.tif)';
            app.MasktifButton.Value = true;
            app.IntensityButton = uiradiobutton(app.FilenamesdtButtonGroup);
            app.IntensityButton.Text = 'Intensity';
            app.EditField = uieditfield(app.CellImageTab, 'text');
            app.LifetimeRangeEditFieldLabel = uilabel(app.CellImageTab);
            app.LifetimeRangeEditFieldLabel.Text = 'Lifetime Range';
            app.LifetimeRangeEditField = uieditfield(app.CellImageTab, 'numeric');
            app.toEditFieldLabel = uilabel(app.CellImageTab);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.CellImageTab, 'numeric');
            app.toEditField_2Label = uilabel(app.CellImageTab);
            app.toEditField_2Label.Text = 'to';
            app.tointensityEditField_2 = uieditfield(app.CellImageTab, 'numeric');
            app.OrmaskbyintensityEditField = uieditfield(app.CellImageTab, 'numeric');
            app.SameasMultiacquisitionCheckBox_3 = uicheckbox(app.CellImageTab);
            app.SameasMultiacquisitionCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @SameasMultiacquisitionCheckBox_3ValueChanged, true);
            app.SameasMultiacquisitionCheckBox_3.Text = 'Same as Multiacquisition';
            app.IRFTab = uitab(app.TabGroup);
            app.IRFTab.Title = 'IRF';
            app.BrowseButton_3 = uibutton(app.IRFTab, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse';
            app.nsLabel_2 = uilabel(app.IRFTab);
            app.nsLabel_2.Text = '[ns]';
            app.IRFImagesdtEditFieldLabel = uilabel(app.IRFTab);
            app.IRFImagesdtEditFieldLabel.Text = 'IRF Image (.sdt)';
            app.IRFImagesdtEditField = uieditfield(app.IRFTab, 'text');
            app.KnownLifetimeLabel = uilabel(app.IRFTab);
            app.KnownLifetimeLabel.Text = 'Known Lifetime';
            app.KnownLifetimeEditField = uieditfield(app.IRFTab, 'numeric');
            app.SameasMultiacquisitionCheckBox_2 = uicheckbox(app.IRFTab);
            app.SameasMultiacquisitionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SameasMultiacquisitionCheckBox_2ValueChanged, true);
            app.SameasMultiacquisitionCheckBox_2.Text = 'Same as Multiacquisition';
            app.CommandsTextAreaLabel = uilabel(app.SingleTab);
            app.CommandsTextAreaLabel.Text = 'Commands';
            app.CommandsTextArea = uitextarea(app.SingleTab);
            app.StartButton = uibutton(app.SingleTab, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.ProcessingModeButtonGroup = uibuttongroup(app.SingleTab);
            app.ProcessingModeButtonGroup.Title = 'Processing Mode';
            app.PhasorplotsonlyButton = uiradiobutton(app.ProcessingModeButtonGroup);
            app.PhasorplotsonlyButton.Text = 'Phasor plots only';
            app.PhasorplotsonlyButton.Value = true;
            app.PhasortoCellButton = uiradiobutton(app.ProcessingModeButtonGroup);
            app.PhasortoCellButton.Text = 'Phasor to Cell';
            app.CelltoPhasorButton = uiradiobutton(app.ProcessingModeButtonGroup);
            app.CelltoPhasorButton.Text = 'Cell to Phasor';
            app.NoofROIDropDownLabel = uilabel(app.ProcessingModeButtonGroup);
            app.NoofROIDropDownLabel.Text = 'No of ROI';
            app.NoofROIDropDown = uidropdown(app.ProcessingModeButtonGroup);
            app.NoofROIDropDown.Items = {'1', '2'};
            app.NoofROIDropDown.Value = '1';
            app.PointsROIDropDownLabel = uilabel(app.ProcessingModeButtonGroup);
            app.PointsROIDropDownLabel.Text = 'Points/ROI';
            app.PointsROIDropDown = uidropdown(app.ProcessingModeButtonGroup);
            app.PointsROIDropDown.Items = {'3', '4', '5', '6', '7', '8', '9', '10'};
            app.PointsROIDropDown.Value = '4';
            app.BrowseButton_11 = uibutton(app.SingleTab, 'push');
            app.BrowseButton_11.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_11Pushed, true);
            app.BrowseButton_11.Text = 'Browse';
            app.SaveinEditField_2Label = uilabel(app.SingleTab);
            app.SaveinEditField_2Label.Text = 'Save in';
            app.SaveinEditField_2 = uieditfield(app.SingleTab, 'text');
            app.CellSegmentationTab = uitab(app.TabGroup3);
            app.CellSegmentationTab.Title = 'Cell Segmentation';
            app.BrowseButton_9 = uibutton(app.CellSegmentationTab, 'push');
            app.BrowseButton_9.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_9Pushed, true);
            app.BrowseButton_9.Text = 'Browse';
            app.CellImagesdtEditField_2Label = uilabel(app.CellSegmentationTab);
            app.CellImagesdtEditField_2Label.Text = 'Cell Image (.sdt)';
            app.CellImagesdtEditField_2 = uieditfield(app.CellSegmentationTab, 'text');
            app.PointsMaskDropDownLabel = uilabel(app.CellSegmentationTab);
            app.PointsMaskDropDownLabel.Text = 'Points/Mask';
            app.PointsMaskDropDown = uidropdown(app.CellSegmentationTab);
            app.PointsMaskDropDown.Items = {'3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.PointsMaskDropDown.Value = '4';
            app.SaveinEditFieldLabel = uilabel(app.CellSegmentationTab);
            app.SaveinEditFieldLabel.Text = 'Save in';
            app.SaveinEditField = uieditfield(app.CellSegmentationTab, 'text');
            app.BrowseButton_10 = uibutton(app.CellSegmentationTab, 'push');
            app.BrowseButton_10.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_10Pushed, true);
            app.BrowseButton_10.Text = 'Browse';
            app.StartButton_3 = uibutton(app.CellSegmentationTab, 'state');
            app.StartButton_3.ValueChangedFcn = createCallbackFcn(app, @StartButton_3ValueChanged, true);
            app.StartButton_3.Text = 'Start';
            app.CommandsTextArea_3Label = uilabel(app.CellSegmentationTab);
            app.CommandsTextArea_3Label.Text = 'Commands';
            app.CommandsTextArea_CellSeg = uitextarea(app.CellSegmentationTab);
            app.FileNamesdtEditFieldLabel = uilabel(app.CellSegmentationTab);
            app.FileNamesdtEditFieldLabel.Text = 'File Name (.sdt)';
            app.FileNamesdtEditField = uieditfield(app.CellSegmentationTab, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.MainTab = uitab(app.TabGroup2);
            app.MainTab.Title = 'Main';
            app.PhasorPlot = uiaxes(app.MainTab);
            title(app.PhasorPlot, 'Phasor Plot')
            xlabel(app.PhasorPlot, '')
            ylabel(app.PhasorPlot, '')
            app.PhasorPlot.YLim = [0 0.5];
            app.PhasorPlot.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PhasorPlot.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.PhasorPlot.YTick = [0 0.1 0.2 0.3 0.4 0.5];
            app.PhasorPlot.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'};
            app.ModulationLifetime = uiaxes(app.MainTab);
            title(app.ModulationLifetime, 'Modulation Lifetime')
            xlabel(app.ModulationLifetime, '')
            ylabel(app.ModulationLifetime, '')
            app.ModulationLifetime.XTick = [];
            app.ModulationLifetime.YTick = [];
            app.PhaseLifetime = uiaxes(app.MainTab);
            title(app.PhaseLifetime, 'Phase Lifetime')
            xlabel(app.PhaseLifetime, '')
            ylabel(app.PhaseLifetime, '')
            app.PhaseLifetime.XTick = [];
            app.PhaseLifetime.YTick = [];
            app.Cbar = uiaxes(app.MainTab);
            title(app.Cbar, 'Colour Bar')
            xlabel(app.Cbar, '')
            ylabel(app.Cbar, '')
            app.Cbar.XTick = [];
            app.Cbar.YTick = [];
            app.CellImageDropDownLabel = uilabel(app.MainTab);
            app.CellImageDropDownLabel.Text = 'Cell Image';
            app.CellImageDropDown = uidropdown(app.MainTab);
            app.CellImageDropDown.Items = {};
            app.CellImageDropDown.ValueChangedFcn = createCallbackFcn(app, @CellImageDropDownValueChanged, true);
            app.CellImageDropDown.Value = {};
            app.TabulatedLifetimes = uitab(app.TabGroup2);
            app.TabulatedLifetimes.Title = 'Tabulated Lifetimes';
            app.UITable = uitable(app.TabulatedLifetimes);
            app.UITable.ColumnName = {'Name'; 'Modulation Lifetime [ns]'; 'Phase Lifetime [ns]'};
            app.UITable.RowName = {};
            app.AverageLifetimesLabel = uilabel(app.TabulatedLifetimes);
            app.AverageLifetimesLabel.Text = 'Average Lifetimes ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhasorPlot_TCSPCFLIM_20200517_GitHub

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end